classdef APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Atividade03UIFigure          matlab.ui.Figure
        AppMenu                      matlab.ui.container.Menu
        RelatorioMenu                matlab.ui.container.Menu
        PaginaMenu                   matlab.ui.container.Menu
        SairMenu                     matlab.ui.container.Menu
        SobreMenu                    matlab.ui.container.Menu
        Comemoracoes25AbrilMenu      matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        ResetButton                  matlab.ui.control.Button
        ExecutarButton               matlab.ui.control.Button
        MetodosNumericosButtonGroup  matlab.ui.container.ButtonGroup
        TodososMetedosButton         matlab.ui.control.ToggleButton
        AdamsButton                  matlab.ui.control.ToggleButton
        ODE45Button                  matlab.ui.control.ToggleButton
        RK4Button                    matlab.ui.control.ToggleButton
        RK2Button                    matlab.ui.control.ToggleButton
        EulerMButton                 matlab.ui.control.ToggleButton
        EulerButton                  matlab.ui.control.ToggleButton
        DadosdoPVIPanel              matlab.ui.container.Panel
        nSpinner                     matlab.ui.control.Spinner
        eY0                          matlab.ui.control.NumericEditField
        eB                           matlab.ui.control.NumericEditField
        eA                           matlab.ui.control.NumericEditField
        nSpinnerLabel                matlab.ui.control.Label
        y0EditFieldLabel             matlab.ui.control.Label
        bEditFieldLabel              matlab.ui.control.Label
        aEditFieldLabel              matlab.ui.control.Label
        eF                           matlab.ui.control.EditField
        ftyEditFieldLabel            matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        UIAxes                       matlab.ui.control.UIAxes
        AbrilPanel                   matlab.ui.container.Panel
        GridLayout3_2                matlab.ui.container.GridLayout
        Image3_2                     matlab.ui.control.Image
        ManuelFurtadoLabel_2         matlab.ui.control.Label
        TiagoFilipeLabel_2           matlab.ui.control.Label
        Image2_2                     matlab.ui.control.Image
        AutoresPanel                 matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        Image3                       matlab.ui.control.Image
        ManuelFurtadoLabel           matlab.ui.control.Label
        TiagoFilipeLabel             matlab.ui.control.Label
        ArmnioCorreiaLabel           matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        Image                        matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButtonPushed(app,[])
            app.AutoresPanel.Visible = "off";
            app.AutoresPanel.Position = [800 1 627 415];
            app.AbrilPanel.Visible = "off";
            app.AbrilPanel.Position = [801,-472,624,436];
        end

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            % Dados
            strF = app.eF.Value;
            f = @(t,y) eval(vectorize(strF));
            a = app.eA.Value;
            b = app.eB.Value;
            n = app.nSpinner.Value;
            y0 = app.eY0.Value;
            MNumericoSel=app.MetodosNumericosButtonGroup.SelectedObject.Text;

            syms y(t);

            errID = "MATLAB:Executar:badF";
            msgtext = "Introduza uma função nas variáveis t e y";
            erroFuncao = MException(errID,msgtext);

        try

                try
	                z = f(t,y);
                    app.eF.BackgroundColor = [1,1,1]; 
                    app.eF.FontColor = [0,0,0];
                catch
                    app.eF.BackgroundColor = [1,0,0];
                    app.eF.FontColor = [1,1,1];
                    throw(erroFuncao);
                end

                if (isscalar(b) && b<a)
                    errordlg("Valores do intervalo [a,b], em que b tem de ser maior que a","Erro detetado", "modal");
                    app.eA.BackgroundColor = [1,0,0];
                    app.eA.FontColor = [1,1,1]; 
                else
                    app.eA.BackgroundColor = [1,1,1];
                    app.eA.FontColor = [0,0,0]; 
                end


                sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                % solução exata do problema (expressão numérica)
                g = @(t) eval(vectorize(char(sExata)));
    
                % resultados e tabela de resultados
                [t,Euler] = NEuler(f,a,b,n,y0);
                [~,EulerM] = Euler_Melhorado(f,a,b,n,y0);
                [~,N2] = NRK2(f,a,b,n,y0);
                [~,N4] = NRK4(f,a,b,n,y0);
                [~,F45] = F_ODE45(f,a,b,n,y0);
                [~,AB] = Adams_Bashworth(f,a,b,y0,n);
    
                yExata = g(t);
                erroEuler = abs(yExata-Euler);
                erroRK2 = abs(yExata-N2);
                erroEulerM = abs(yExata-EulerM);
                erroRK4 = abs(yExata-N4);
                erroODE = abs(yExata-F45);
                erroAB = abs(yExata-AB);
    
                plot(app.UIAxes,t,yExata,"-bo");
                hold(app.UIAxes,"on");

                    switch MNumericoSel

                        case "Euler"
                           plot(app.UIAxes,t,Euler,"-g*");
                           legend(app.UIAxes,'Exata','Euler');
                           A = [t.',yExata.',Euler.',erroEuler.'];                           
                           nomeColunas = ["t","Exata","Euler","erroEuler"];
                        
                        case "Euler M"
                           plot(app.UIAxes,t,EulerM,"-r+");
                           legend(app.UIAxes,'Exata',"Euler M");
                           A = [t.',yExata.',EulerM.', erroEulerM.'];
                           nomeColunas = ["t","Exata","Euler M","erroEuler M"];

                         case "RK2"
                            plot(app.UIAxes,t,N2,"-y+");
                            legend(app.UIAxes,'Exata',"RK2");
                            A = [t.',yExata.',N2.',erroRK2.'];
                            nomeColunas = ["t","Exata","RK2","erroRK2"];
    
                         case "RK4"
                            plot(app.UIAxes,t,N4,"-y+");
                            legend(app.UIAxes,'Exata',"RK4");
                            A = [t.',yExata.',N4.',erroRK4.'];
                            nomeColunas = ["t","Exata","RK4","erroRK4"];

                          case "ODE45"
                            plot(app.UIAxes,t,F45,"-c+");
                            legend(app.UIAxes,'Exata',"ODE 45");
                            A = [t.',yExata.',F45.',erroODE.'];
                            nomeColunas = ["t","Exata","ODE 45","erroODE 45"];

                        case "Adams"
                            plot(app.UIAxes,t,AB,"-m+");
                            legend(app.UIAxes,'Exata',"Adams");
                            A = [t.',yExata.',AB.',erroAB.'];
                            nomeColunas = ["t","Exata","AB","erroAB"];
                        
                        case "Todos os Métedos" 
                            plot(app.UIAxes,t,Euler,"-g*");
                            plot(app.UIAxes,t,EulerM,"-k+");
                            plot(app.UIAxes,t,N2,"r--");
                            plot(app.UIAxes,t,N4,"-y+");
                            plot(app.UIAxes,t,F45,"-c*");
                            plot(app.UIAxes,t,AB,"-mo");
                            legend(app.UIAxes,'Exata','Euler',"Euler M","RK2", "RK4", "ODE 45", "Adams");
                            A = [t.',yExata.',Euler.',erroEuler.',EulerM.',erroEulerM.',N2.',erroRK2.',N4.',erroRK4.',F45.',erroODE.',AB.',erroAB.'];       
                            nomeColunas = ["t","Exata","Euler","erroEuler","Euler M","erroEuler M","RK2","erroRK2","RK4","erroRK4","ODE 45","erroODE 45", "Adams", "erroAdams"];
                        
                        otherwise
                            warndlg("Métedo nao implementado","Aviso","modal")
                            A = [];
                            nomeColunas = [];
                    end
            
                         hold(app.UIAxes,"off");
                         grid(app.UIAxes,"on"),

                         tabelaResultados = array2table(A);
                         app.UITable.Data = tabelaResultados;
                         app.UITable.ColumnName = nomeColunas;

        catch Me
            errordlg(Me.message,"Erro detetado", "modal");
        end
        end

        % Menu selected function: PaginaMenu
        function PaginaMenuSelected(app, event)
            web("www.isec.pt")
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?","Sair","Sim","Não","Sim");

            if strcmp(SN,"Sim")
                delete(app.Atividade03UIFigure)
            end
        end

        % Close request function: Atividade03UIFigure
        function Atividade03UIFigureCloseRequest(app, event)
            SairMenuSelected(app, event)
            
        end

        % Menu selected function: RelatorioMenu
        function RelatorioMenuSelected(app, event)
             open("Relatorio Atividade 3.pdf");
             open("Relatório Sobre a APP Matlab.pdf");
        end

        % Menu selected function: SobreMenu
        function SobreMenuSelected(app, event)
            % Autores;
            app.LeftPanel.Visible = "off";
            app.RightPanel.Visible = "off";
            app.AutoresPanel.Visible = "on";
            app.AbrilPanel.Visible = "off";
            app.AutoresPanel.Position =[1 1 627 415];


        end

        % Menu selected function: AppMenu
        function AppMenuSelected(app, event)
            app.LeftPanel.Visible = "on";
            app.RightPanel.Visible = "on";
            app.AutoresPanel.Visible = "off";
            app.AbrilPanel.Visible = "off";
            app.AutoresPanel.Position = [800 1 627 415];
        end

        % Menu selected function: Comemoracoes25AbrilMenu
        function Comemoracoes25AbrilMenuSelected(app, event)
            app.LeftPanel.Visible = "off";
            app.RightPanel.Visible = "off";
            app.AutoresPanel.Visible = "off";
            app.AbrilPanel.Visible = "on";
            app.AbrilPanel.Position = [1 1 627 415];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Atividade03UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {415, 415};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {231, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Atividade03UIFigure = uifigure('Visible', 'off');
            app.Atividade03UIFigure.Name = 'Atividade03';
            app.Atividade03UIFigure.CloseRequestFcn = createCallbackFcn(app, @Atividade03UIFigureCloseRequest, true);
            app.Atividade03UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.AppMenu = uimenu(app.Atividade03UIFigure);
            app.AppMenu.MenuSelectedFcn = createCallbackFcn(app, @AppMenuSelected, true);
            app.AppMenu.Text = 'App';
            app.RelatorioMenu = uimenu(app.AppMenu);
            app.RelatorioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatorioMenuSelected, true);
            app.RelatorioMenu.Text = 'Relatório';
            app.PaginaMenu = uimenu(app.AppMenu);
            app.PaginaMenu.MenuSelectedFcn = createCallbackFcn(app, @PaginaMenuSelected, true);
            app.PaginaMenu.Text = 'Página';
            app.SairMenu = uimenu(app.AppMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.SobreMenu = uimenu(app.Atividade03UIFigure);
            app.SobreMenu.MenuSelectedFcn = createCallbackFcn(app, @SobreMenuSelected, true);
            app.SobreMenu.Text = 'Sobre';
            app.Comemoracoes25AbrilMenu = uimenu(app.Atividade03UIFigure);
            app.Comemoracoes25AbrilMenu.MenuSelectedFcn = createCallbackFcn(app, @Comemoracoes25AbrilMenuSelected, true);
            app.Comemoracoes25AbrilMenu.Text = 'Comemorações 25 Abril';
            app.GridLayout = uigridlayout(app.Atividade03UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdoPVIPanel = uipanel(app.LeftPanel);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.ftyEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.ftyEditFieldLabel.Text = 'f(t,y) =';
            app.eF = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eF.Value = 'y+exp(3*t)';
            app.aEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.bEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.bEditFieldLabel.Text = 'b';
            app.y0EditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.y0EditFieldLabel.Text = 'y0';
            app.nSpinnerLabel = uilabel(app.DadosdoPVIPanel);
            app.nSpinnerLabel.Text = 'n';
            app.eA = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eA.Value = 1;
            app.eB = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB.Value = 1.5;
            app.eY0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eY0.Value = 2;
            app.nSpinner = uispinner(app.DadosdoPVIPanel);
            app.nSpinner.Limits = [1 Inf];
            app.nSpinner.Value = 2;
            app.MetodosNumericosButtonGroup = uibuttongroup(app.LeftPanel);
            app.MetodosNumericosButtonGroup.Title = 'Métodos Numéricos';
            app.EulerButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.EulerButton.Text = 'Euler';
            app.EulerButton.Value = true;
            app.EulerMButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.EulerMButton.Text = 'Euler M';
            app.RK2Button = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.RK2Button.Text = 'RK2';
            app.RK4Button = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.RK4Button.Text = 'RK4';
            app.ODE45Button = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.ODE45Button.Text = 'ODE45';
            app.AdamsButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.AdamsButton.Text = 'Adams';
            app.TodososMetedosButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.TodososMetedosButton.Text = 'Todos os Métedos';
            app.ExecutarButton = uibutton(app.LeftPanel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.AutoresPanel = uipanel(app.Atividade03UIFigure);
            app.AutoresPanel.Title = 'Autores';
            app.GridLayout3 = uigridlayout(app.AutoresPanel);
            app.Image = uiimage(app.GridLayout3);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Imagens', 'armenioc.jpg');
            app.Image2 = uiimage(app.GridLayout3);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Imagens', 'tiagof.jpg');
            app.ArmnioCorreiaLabel = uilabel(app.GridLayout3);
            app.ArmnioCorreiaLabel.Text = 'Arménio Correia';
            app.TiagoFilipeLabel = uilabel(app.GridLayout3);
            app.TiagoFilipeLabel.Text = 'Tiago Filipe';
            app.ManuelFurtadoLabel = uilabel(app.GridLayout3);
            app.ManuelFurtadoLabel.Text = 'Manuel Furtado';
            app.Image3 = uiimage(app.GridLayout3);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Imagens', 'manuel.jpg');
            app.AbrilPanel = uipanel(app.Atividade03UIFigure);
            app.AbrilPanel.Title = '25 Abril';
            app.GridLayout3_2 = uigridlayout(app.AbrilPanel);
            app.Image2_2 = uiimage(app.GridLayout3_2);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'Imagens', '25abril_Tiago.jpg');
            app.TiagoFilipeLabel_2 = uilabel(app.GridLayout3_2);
            app.TiagoFilipeLabel_2.Text = 'Tiago Filipe';
            app.ManuelFurtadoLabel_2 = uilabel(app.GridLayout3_2);
            app.ManuelFurtadoLabel_2.Text = 'Manuel Furtado';
            app.Image3_2 = uiimage(app.GridLayout3_2);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, 'Imagens', '25abril_Manuel.jpeg.jpg');
            % Show the figure after all components are created
            app.Atividade03UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Atividade03UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Atividade03UIFigure)
        end
    end
end