classdef Maquina01MNSED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaquinaMNSEDUIFigure         matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        RelatorioMenu                matlab.ui.container.Menu
        PaginaWebMenu                matlab.ui.container.Menu
        SairMenu                     matlab.ui.container.Menu
        SobreMenu                    matlab.ui.container.Menu
        AutoresMenu                  matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        ProblemasDropDown            matlab.ui.control.DropDown
        ProblemasdinmicosLabel       matlab.ui.control.Label
        GrelhaCheckBox               matlab.ui.control.CheckBox
        ResetButton                  matlab.ui.control.Button
        ExecutarButton               matlab.ui.control.Button
        MetodosNumericosButtonGroup  matlab.ui.container.ButtonGroup
        TodososMetodosButton         matlab.ui.control.ToggleButton
        RK4Button                    matlab.ui.control.ToggleButton
        RK2Button                    matlab.ui.control.ToggleButton
        EulerMButton                 matlab.ui.control.ToggleButton
        EulerButton                  matlab.ui.control.ToggleButton
        DadosdoPVIPanel              matlab.ui.container.Panel
        eV0                          matlab.ui.control.NumericEditField
        aEditFieldLabel_4            matlab.ui.control.Label
        eG                           matlab.ui.control.EditField
        gtuvLabel                    matlab.ui.control.Label
        eU0                          matlab.ui.control.NumericEditField
        aEditFieldLabel_3            matlab.ui.control.Label
        nSpinner                     matlab.ui.control.Spinner
        nSpinnerLabel                matlab.ui.control.Label
        aEditFieldLabel_2            matlab.ui.control.Label
        eB                           matlab.ui.control.NumericEditField
        eA                           matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        eF                           matlab.ui.control.EditField
        ftuvLabel                    matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)
        
        function [t,u,v] = NRK2SED(~,f,g,a,b,n,u0,v0)
            %NRK2SED 
            % Resumo da função
            %INPUT:
            %   f - função de ordem 1 de u
            %   g - função de ordem 1 de v
            %   [a,b] - intervalo de valores da variável independente t
            %   n - número de subintervalos, iterações do método
            %   u0 - condição inicial f(a)=u0
            %   v0 - condição inicial g(a)=v0
            %OUTPUT:
            %   [t, u, v] - vetor das soluções aproximada
            %   t - pontos igualmente espaçados no intervalo [a, b]
            %   u - vetor das soluções aproximadas de u(t)
            %   v - vetor das soluções aproximadas de v(t)
            % 
            % Alunos: Manuel Furtado a2023154006
            %         Tiago Filipe a2019112767
            %   17/04/2024  Arménio Correia     armenioc@isec.pt
            h = (b-a)/n;
            t = a:h:b;
            u = zeros(1,n+1); v = zeros(1,n+1);
            u(1) = u0; v(1) = v0;
            for i = 1:n
               
                k1u = h*f(t(i),u(i),v(i));
                k1v = h*g(t(i),u(i),v(i));
                
                k2u = h*f(t(i+1),u(i)+k1u,v(i)+k1v);
                k2v = h*g(t(i+1),u(i)+k1u,v(i)+k1v);
                
                u(i+1) = u(i)+(k1u+k2u)/2;
                v(i+1) = v(i)+(k1v+k2v)/2;
            end            
        end
        
        function [t,u,v] = NRK4SED(~,f,g,a,b,n,u0,v0)
            %NRK4SED 
            % Resumo da função
            %INPUT:
            %   f - função de ordem 1 de u
            %   g - função de ordem 1 de v
            %   [a,b] - intervalo de valores da variável independente t
            %   n - número de subintervalos, iterações do método
            %   u0 - condição inicial f(a)=u0
            %   v0 - condição inicial g(a)=v0
            %OUTPUT:
            %   [t, u, v] - vetor das soluções aproximada
            %   t - pontos igualmente espaçados no intervalo [a, b]
            %   u - vetor das soluções aproximadas de u(t)
            %   v - vetor das soluções aproximadas de v(t)
            % 
            % Alunos: Manuel Furtado a2023154006
            %         Tiago Filipe a2019112767

            h = (b-a)/n;
            t = a:h:b;
            u = zeros(1,n+1); v = zeros(1,n+1);
            u(1) = u0; v(1) = v0;

            for i = 1:n

                k1u=h*f(t(i), u(i), v(i));
                k1v=h*g(t(i), u(i), v(i));

                k2u=h*f(t(i)+h/2, u(i)+k1u/2, v(i)+k1v/2);
                k2v=h*g(t(i)+h/2, u(i)+k1u/2, v(i)+k1v/2);

                k3u=h*f(t(i)+h/2, u(i)+k2u/2, v(i)+k2v/2);
                k3v=h*g(t(i)+h/2, u(i)+k2u/2, v(i)+k2v/2);

                k4u=h*f(t(i)+h, u(i)+k3u, v(i)+k3v);
                k4v=h*g(t(i)+h, u(i)+k3u, v(i)+k3v);

                u(i+1)= u(i)+ (k1u+2*k2u+2*k3u+k4u)/6;
                v(i+1)= v(i)+ (k1v+2*k2v+2*k3v+k4v)/6;

            end
        end
        
        function [t,u,v] = NEulerMSED(~,f,g,a,b,n,u0,v0)
            %NEulerMSED - Euler Melhorado 
            % Resumo da função
            %INPUT:
            %   f - função de ordem 1 de u
            %   g - função de ordem 1 de v
            %   [a,b] - intervalo de valores da variável independente t
            %   n - número de subintervalos, iterações do método
            %   u0 - condição inicial f(a)=u0
            %   v0 - condição inicial g(a)=v0
            %OUTPUT:
            %   [t, u, v] - vetor das soluções aproximada
            %   t - pontos igualmente espaçados no intervalo [a, b]
            %   u - vetor das soluções aproximadas de u(t)
            %   v - vetor das soluções aproximadas de v(t)
            % 
            % Alunos: Manuel Furtado a2023154006
            %         Tiago Filipe a2019112767

            h = (b-a)/n;
            t = a:h:b;
            u = zeros(1,n+1); v = zeros(1,n+1);
            u(1) = u0; v(1) = v0;
            
            for i = 1:n
                u(i+1) = u(i)+h*f(t(i),u(i),v(i));
                v(i+1) = v(i)+h*g(t(i),u(i),v(i));

                u(i+1)=u(i)+(h/2)*(f(t(i),u(i),v(i))+f(t(i+1),u(i+1),v(i+1)));
                v(i+1)=v(i)+(h/2)*(g(t(i),u(i),v(i))+g(t(i+1),u(i+1),v(i+1)));
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButtonPushed(app,[])
        end

        % Callback function: ExecutarButton, MetodosNumericosButtonGroup, 
        % ...and 1 other component
        function ExecutarButtonPushed(app, event)
            syms u(t) v(t)
            strF = app.eF.Value;
            f=@(t,u,v) eval(vectorize(strF));
            strG = app.eG.Value;
            g=@(t,u,v) eval(vectorize(strG));           
            a = app.eA.Value; b = app.eB.Value; n = app.nSpinner.Value;
            u0 = app.eU0.Value; v0 = app.eV0.Value;

            erroGtuv = MException("MATLAB:Executar:badG","Introduza uma função em t, u e v");
            %outras exceções

            MNumericoSel = app.MetodosNumericosButtonGroup.SelectedObject.Text;

            try
                try
                    z = g(t,u,v);
                    app.eG.BackgroundColor = "w";
                catch
                    app.eG.BackgroundColor = "r";
                    throw(erroGtuv)
                end

                SED = [diff(u,t)==f(t,u,v),diff(v,t)==g(t,u,v)];
                ci  = [u(a)==u0,v(a)==v0];
                [uExata,~] = dsolve(SED,ci);
                h =@(t) eval(vectorize(char(uExata)));


 if(app.ProblemasDropDown.Value == 3)
                    [t,uEuler,vEuler] = NEulerSED(f,g,a,b,n,u0,v0);
                    [~,uEulerM,vEulerM] = NEulerMSED(app,f,g,a,b,n,u0,v0);
                    [~,uRK2,vRK2] = NRK2SED(app,f,g,a,b,n,u0,v0);
                    [~,uRK4,vRK4] = NRK4SED(app,f,g,a,b,n,u0,v0);
                  
                    switch MNumericoSel
                        case "Euler"
                            plot(app.UIAxes,t,uEuler,t,vEuler,"-r*")
                            A = [t.',uEuler.',vEuler.'];
                            tabelaResultados = array2table(A,"VariableNames",["t","Deslocamento","Velocidade"]);
                            legendaGrafico = ["Deslocamento","Velocidade"];
                            nomeColunas = ["t","Deslocamento","Velocidade"];
                        case "Euler M"
                            plot(app.UIAxes,t,uEulerM,t,vEulerM,"-r*")
                            A = [t.',uEulerM.',vEulerM.'];
                            tabelaResultados = array2table(A,"VariableNames",["t","Deslocamento","Velocidade"]);
                            legendaGrafico = ["Deslocamento","Velocidade"];
                            nomeColunas = ["t","Deslocamento","Velocidade"];
                        case "RK2"
                            plot(app.UIAxes,t,uRK2,t,vRK2,"-r*")
                            A = [t.',uRK2.',vRK2.'];
                            tabelaResultados = array2table(A,"VariableNames",["t","Deslocamento","Velocidade"]);
                            legendaGrafico = ["Deslocamento","Velocidade"];
                            nomeColunas = ["t","Deslocamento","Velocidade"];
                        case "RK4"
                            plot(app.UIAxes,t,uRK4,t,vRK4,"-r*")
                            A = [t.',uRK4.',vRK4.'];
                            tabelaResultados = array2table(A,"VariableNames",["t","Deslocamento","Velocidade"])
                            legendaGrafico = ["Deslocamento","Velocidade"];
                            nomeColunas = ["t","Deslocamento","Velocidade"];
                        case "Todos os Métodos"
                            plot(app.UIAxes, t, uEuler, t, uEulerM, t, uRK2, t, uRK4)
                            legendaGrafico = ["Exata","Euler","EulerM","RK2","RK4"];
                            A = [t.',uEuler.',uEulerM.',uRK2.',uRK4.'];
                            nomeColunas = ["t","uEuler","uEulerM","uRK2","uRK4"];
                    end
                    
 else

                [t,uEuler,~] = NEulerSED(f,g,a,b,n,u0,v0);
                [~,uEulerM,~] = NEulerMSED(app,f,g,a,b,n,u0,v0);
                [~,uRK2,~] = NRK2SED(app,f,g,a,b,n,u0,v0);
                [~,uRK4,~] = NRK4SED(app,f,g,a,b,n,u0,v0);
                % Chamar as outras funções/métodos
                
                uExata = h(t);
                erroEuler = abs(uExata-uEuler);
                erroEulerM = abs(uExata-uEulerM);
                erroRK2 = abs(uExata-uRK2);
                erroRK4 = abs(uExata-uRK4);
                % mais erros

                plot(app.UIAxes,t,uExata,"-ro")
                hold(app.UIAxes,"on")

                switch MNumericoSel
                    case "Euler"
                        plot(app.UIAxes,t,uEuler,"-g*")
                        legendaGrafico = ["Exata","Euler"];
                        A = [t.',uExata.',uEuler.',erroEuler.'];
                        nomeColunas = ["t","uExata","uEuler","erroEuler"];
                    case "Euler M"
                        plot(app.UIAxes,t,uEulerM,"-g*")
                        legendaGrafico = ["Exata","EulerM"];
                        A = [t.',uExata.',uEulerM.',erroEulerM.'];
                        nomeColunas = ["t","uExata","uEulerM","erroEulerM"];
                    case "RK2"
                        plot(app.UIAxes,t,uRK2,"-b+")
                        legendaGrafico = ["Exata","RK2"];
                        A = [t.',uExata.',uRK2.',erroRK2.'];
                        nomeColunas = ["t","uExata","uRK2","erroRK2"];
                    case "RK4"
                        plot(app.UIAxes,t,uRK4,"-b+")
                        legendaGrafico = ["Exata","RK4"];
                        A = [t.',uExata.',uRK2.',erroRK4.'];
                        nomeColunas = ["t","uExata","uRK4","erroRK4"];
                    case "Todos os Métodos"
                        plot(app.UIAxes,t,uEuler,"-g*")
                        plot(app.UIAxes,t,uRK2,"-b+")
                        plot(app.UIAxes,t,uRK4,"-co")
                        plot(app.UIAxes,t,uEulerM,"-ks")
                        legendaGrafico = ["Exata","Euler","EulerM","RK2","RK4"];
                        A = [t.',uExata.',uEuler.',uEulerM.',uRK2.',uRK4.',erroEuler.',erroEulerM.',erroRK2.',erroRK4.'];
                        nomeColunas = ["t","uExata","uEuler","uEulerM","uRK2","uRK4","erroEuler","erroEulerM","erroRK2","erroRK4"];                        
                end
 end
                 
                legend(app.UIAxes,legendaGrafico) 
                %grid(app.UIAxes,"on")
                hold(app.UIAxes,"off")

                tabelaResultados = array2table(A);
                app.UITable.Data = tabelaResultados;
                app.UITable.ColumnName = nomeColunas;
            
            catch Me
                errordlg(Me.message,"ERRO","modal")
            end

        end

        % Value changed function: GrelhaCheckBox
        function GrelhaCheckBoxValueChanged(app, event)
            value = app.GrelhaCheckBox.Value;
            if value
                grid(app.UIAxes,"on")
            else
                grid(app.UIAxes,"off")
            end
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN, "Sim")
                delete(app.MaquinaMNSEDUIFigure)
            end
        end

        % Close request function: MaquinaMNSEDUIFigure
        function MaquinaMNSEDUIFigureCloseRequest(app, event)
            %delete(app)
            SairMenuSelected(app, event)
        end

        % Menu selected function: RelatorioMenu
        function RelatorioMenuSelected(app, event)
            open("RelatorioTP4SED.pdf")
        end

        % Menu selected function: PaginaWebMenu
        function PaginaWebMenuSelected(app, event)
            web("www.isec.pt")
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            autores
        end

        % Value changed function: ProblemasDropDown
        function ProblemasDropDownValueChanged(app, event)
            value = app.ProblemasDropDown.Value;
            switch value
                case 1 %Mola-massa-sem-amotecimento
                    strG = "-2*u + 4*cos(t) + 2*cos(t)";
                    a = 0; b = 20; n = 100; u0 = 0; v0 = 3;
                case 2 %Mola-massa-com-amotecimento
                    strG = "-2*v - 2*u + 4*cos(t)+2*sin(t)"; 
                    a = 0; b = 20; n = 100; u0 = 0; v0 = 3;
                case 3 %pendulo
                    strG = "-sin(u)-0.3*v"; 
                    a = 0; b = 15; n = 100; u0 = pi/2; v0 = 0;
                case 4 %circuito eletrico
                    strG = "-22*v-6*sin(t)-(1/0.5)*u"; 
                    a = 0; b = 8; n = 100; u0 = 0; v0 = 0; 
                case 5 %Outro
                    strG = "-10*v+0.1*u"; 
                    a = 0; b = 25; n = 100; u0 = 50; v0 = 0;
            end

            app.eG.Value = strG;
            app.eA.Value = a; app.eB.Value = b; app.nSpinner.Value = n;
            app.eU0.Value= u0; app.eV0.Value = v0;
            ExecutarButtonPushed(app, event)      
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MaquinaMNSEDUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {435, 435};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {223, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MaquinaMNSEDUIFigure = uifigure('Visible', 'off');
            app.MaquinaMNSEDUIFigure.Name = 'MaquinaMNSED';
            app.MaquinaMNSEDUIFigure.CloseRequestFcn = createCallbackFcn(app, @MaquinaMNSEDUIFigureCloseRequest, true);
            app.MaquinaMNSEDUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.MaquinaMNSEDUIFigure);
            app.FileMenu.Text = 'File';
            app.RelatorioMenu = uimenu(app.FileMenu);
            app.RelatorioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatorioMenuSelected, true);
            app.RelatorioMenu.Text = 'Relatório';
            app.PaginaWebMenu = uimenu(app.FileMenu);
            app.PaginaWebMenu.MenuSelectedFcn = createCallbackFcn(app, @PaginaWebMenuSelected, true);
            app.PaginaWebMenu.Text = 'PáginaWeb';
            app.SairMenu = uimenu(app.FileMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.SobreMenu = uimenu(app.MaquinaMNSEDUIFigure);
            app.SobreMenu.Text = 'Sobre';
            app.AutoresMenu = uimenu(app.SobreMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.GridLayout = uigridlayout(app.MaquinaMNSEDUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdoPVIPanel = uipanel(app.LeftPanel);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.ftuvLabel = uilabel(app.DadosdoPVIPanel);
            app.ftuvLabel.Text = 'f(t,u,v) =';
            app.eF = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eF.Editable = 'off';
            app.eF.Value = 'v';
            app.aEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eA.Value = 1;
            app.eB = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB.Value = 8;
            app.aEditFieldLabel_2 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_2.Text = 'b';
            app.nSpinnerLabel = uilabel(app.DadosdoPVIPanel);
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.DadosdoPVIPanel);
            app.nSpinner.Limits = [2 Inf];
            app.nSpinner.ValueDisplayFormat = '%.0f';
            app.nSpinner.ValueChangedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.nSpinner.Value = 100;
            app.aEditFieldLabel_3 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_3.Text = 'u0 = ';
            app.eU0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eU0.Value = 9;
            app.gtuvLabel = uilabel(app.DadosdoPVIPanel);
            app.gtuvLabel.Text = 'g(t,u,v) =';
            app.eG = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eG.Value = '-16*u';
            app.aEditFieldLabel_4 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_4.Text = 'v0 =';
            app.eV0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.MetodosNumericosButtonGroup = uibuttongroup(app.LeftPanel);
            app.MetodosNumericosButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.MetodosNumericosButtonGroup.Title = 'Métodos Numéricos';
            app.EulerButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.EulerButton.Text = 'Euler';
            app.EulerButton.Value = true;
            app.EulerMButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.EulerMButton.Text = 'Euler M';
            app.RK2Button = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.RK2Button.Text = 'RK2';
            app.RK4Button = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.RK4Button.Text = 'RK4';
            app.TodososMetodosButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.TodososMetodosButton.Text = 'Todos os Métodos';
            app.ExecutarButton = uibutton(app.LeftPanel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.Text = 'Reset';
            app.GrelhaCheckBox = uicheckbox(app.LeftPanel);
            app.GrelhaCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrelhaCheckBoxValueChanged, true);
            app.GrelhaCheckBox.Text = 'Grelha';
            app.GrelhaCheckBox.Value = true;
            app.ProblemasdinmicosLabel = uilabel(app.LeftPanel);
            app.ProblemasdinmicosLabel.Text = 'Problemas dinâmicos';
            app.ProblemasDropDown = uidropdown(app.LeftPanel);
            app.ProblemasDropDown.Items = {'Mola Massa sem amortecimento', 'Mola Massa com Amortecimento', 'Pêndulo', 'Circuito Elétrico', 'Outro'};
            app.ProblemasDropDown.ItemsData = [1 2 3 4 5];
            app.ProblemasDropDown.ValueChangedFcn = createCallbackFcn(app, @ProblemasDropDownValueChanged, true);
            app.ProblemasDropDown.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'u')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.MaquinaMNSEDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Maquina01MNSED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MaquinaMNSEDUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaquinaMNSEDUIFigure)
        end
    end
end