classdef Maquina03CDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaquinaCDIUIFigure             matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        zfxyTab                        matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        Panel_2                        matlab.ui.container.Panel
        LabelLaplaceEDP                matlab.ui.control.Label
        LabelHarmonica                 matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        eD                             matlab.ui.control.NumericEditField
        eH2                            matlab.ui.control.NumericEditField
        eC                             matlab.ui.control.NumericEditField
        bEditFieldLabel_2              matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        h1EditFieldLabel_2             matlab.ui.control.Label
        aEditFieldLabel_2              matlab.ui.control.Label
        yLabel                         matlab.ui.control.Label
        eB                             matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        eH1                            matlab.ui.control.NumericEditField
        h1EditFieldLabel               matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        eA                             matlab.ui.control.NumericEditField
        aEditFieldLabel                matlab.ui.control.Label
        xLabel                         matlab.ui.control.Label
        ExecutarButton                 matlab.ui.control.Button
        eFxy                           matlab.ui.control.EditField
        fxyEditFieldLabel              matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        yfxTab                         matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        Panel_3                        matlab.ui.container.Panel
        RP                             matlab.ui.control.Label
        nSpinner                       matlab.ui.control.Spinner
        bLabel                         matlab.ui.control.Label
        hLabel                         matlab.ui.control.Label
        IntegracaoNumerica             matlab.ui.control.ListBox
        IntegraoNumricaListBoxLabel    matlab.ui.control.Label
        rdgCalculode                   matlab.ui.container.ButtonGroup
        rdPrimitivas                   matlab.ui.control.RadioButton
        rdDerivadas                    matlab.ui.control.RadioButton
        DerivacaoNumericaButtonGroup   matlab.ui.container.ButtonGroup
        rdDF3P                         matlab.ui.control.RadioButton
        rdDF3C                         matlab.ui.control.RadioButton
        rd2DF3p                        matlab.ui.control.RadioButton
        rdDF3R                         matlab.ui.control.RadioButton
        rdDFR                          matlab.ui.control.RadioButton
        rdDFP                          matlab.ui.control.RadioButton
        eB_2                           matlab.ui.control.NumericEditField
        eH                             matlab.ui.control.NumericEditField
        eA_2                           matlab.ui.control.NumericEditField
        aEditFieldLabel_3              matlab.ui.control.Label
        eFx                            matlab.ui.control.EditField
        fxLabel                        matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        xLabel_2                       matlab.ui.control.Label
        ExecutarButton_2               matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        CoordenadasTab                 matlab.ui.container.Tab
        transCoord                     matlab.ui.control.Label
        TransformaodecoordenadasPanel  matlab.ui.container.Panel
        Lc3                            matlab.ui.control.Label
        Lc2                            matlab.ui.control.Label
        Lc1                            matlab.ui.control.Label
        C3                             matlab.ui.control.NumericEditField
        C2                             matlab.ui.control.NumericEditField
        C1                             matlab.ui.control.NumericEditField
        ExecutarButton_3               matlab.ui.control.Button
        TCI                            matlab.ui.container.ButtonGroup
        rdE                            matlab.ui.control.RadioButton
        rdCi                           matlab.ui.control.RadioButton
        rdCa                           matlab.ui.control.RadioButton
        AutoresTab                     matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        TiagoFilipeLabel               matlab.ui.control.Label
        ManuelFurtadoLabel             matlab.ui.control.Label
        ArmnioCorreiaLabel             matlab.ui.control.Label
        Image3                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
    end

    
    methods (Access = public)
        
        % Teste se uma função é Harmónica
        function YN = isHarmonica(~,f)
            syms z(x,y)
            if (simplify(diff(f,x,2)+diff(diff(f,y),y))==0)
                YN = 1;
            else
                YN = 0;
            end          
        end
        
        function [x,y,dydx] = NDerivadaDFP(~,f,a,b,h,y) 
            % Função NDerivadaDFP para calcular a diferenças finitas 2 pontos Progressiva
            % Entrada:
            % f - função a ser derivada
            % a - limite inferior do intervalo
            % b - limite superior do intervalo
            % h - passo
            % y - valores da função (ocional)
            % Saída:
            % x - pontos de avaliação no intervalo [a, b]
            % y - valores de f(x) nos pontos de avaliação
            % dydx - valores aproximados
            x = a:h:b;
            n = length(x);
            if nargin == 5 %deteta o numero de argumentos na funçao
                y = f(x);  %se nao recebermos y, y passa a ser igual a f(x)
            end
            dydx = zeros(1,n);
            for k = 1:n-1
                dydx(k) = (y(k+1)-y(k))/h; %utilizando o metedo das progressivas 
            end                            %valor do ponto - o proximo ponto a dividir por h = derivada
            dydx(n) = (y(n)-y(n-1))/h; %ultimo ponto usa o metodo da regressiva
            %dydx(n) = dydx(n-1)
        end

        function [x,y,dydx] = NDerivadaDFR(~,f,a,b,h,y)
            % Função NDerivadaDFR para calcular a diferenças finitas 2 pontos Regressiva
            % Entrada:
            % f - função a ser derivada
            % a - limite inferior do intervalo
            % b - limite superior do intervalo
            % h - passo
            % y - valores da função (ocional)
            % Saída:
            % x - pontos de avaliação no intervalo [a, b]
            % y - valores de f(x) nos pontos de avaliação
            % dydx - valores aproximados
            x = a:h:b;
            n = length(x);
            if nargin == 5 %deteta o numero de argumentos na funçao
                y = f(x);  %se nao recebermos y, y passa a ser igual a f(x)
            end
            dydx = zeros(1,n);
            dydx(1) = (y(2)-y(1))/h; %utilizando metedo das progressivas
            for k = 2:n
               dydx(k) = (y(k)-y(k-1))/h; %utilizando o metedo das regressivas
            end   
        end

        function [x,y,dydx] = NDerivadaDF3R(~,f,a,b,h,y)
            % Função NDerivadaDF3R para calcular a diferenças finitas 3 pontos Regressiva
            % Entrada:
            % f - função a ser derivada
            % a - limite inferior do intervalo
            % b - limite superior do intervalo
            % h - passo
            % y - valores da função (ocional)
            % Saída:
            % x - pontos de avaliação no intervalo [a, b]
            % y - valores de f(x) nos pontos de avaliação
            % dydx - valores aproximados
            x = a:h:b;
            n = length(x);
            if nargin == 5 %deteta o numero de argumentos na funçao
                y = f(x);  %se nao recebermos y, y passa a ser igual a f(x)
            end
           
                dydx = zeros(1,n);
                dydx(1) = (-3*y(1)+4*y(2)-y(3))/(2*h); %utilizando metedo das progressivas
                dydx(2) = (-3*y(1)+4*y(2)-y(3))/(2*h);
                for k = 3:n
                   dydx(k) = (y(k-2)-4*y(k-1)+3*y(k))/(2*h); %utilizando o metedo das regressivas
                end

        end

        function [x,y,dydx] = NDerivadaDF3C(~,f,a,b,h,y)
            % Função NDerivadaDF3C para calcular a diferenças finitas 3 pontos Centrada
            % Entrada:
            % f - função a ser derivada
            % a - limite inferior do intervalo
            % b - limite superior do intervalo
            % h - passo
            % y - valores da função (ocional)
            % Saída:
            % x - pontos de avaliação no intervalo [a, b]
            % y - valores de f(x) nos pontos de avaliação
            % dydx - valores aproximados
            x = a:h:b;
            n = length(x);
            if nargin == 5 %deteta o numero de argumentos na funçao
                y = f(x);  %se nao recebermos y, y passa a ser igual a f(x)
            end
           
                dydx = zeros(1,n);
                dydx(1) = (-3*y(1)+4*y(2)-y(3))/(2*h); %utilizando metedo das progressivas
                for k = 2:n-1
                   dydx(k) = (y(k+1)-y(k-1))/(2*h); %utilizando o metedo das regressivas
                end
                dydx(n) = (y(n-2)-4*y(n-1)+3*y(n))/(2*h);

        end


        function [x,y,dydx] = NDerivadaDF3P(~,f,a,b,h,y)
            % Função NDerivadaDF3P para calcular a diferenças finitas 3 pontos Progressiva
            % Entrada:
            % f - função a ser derivada
            % a - limite inferior do intervalo
            % b - limite superior do intervalo
            % h - passo
            % y - valores da função (ocional)
            % Saída:
            % x - pontos de avaliação no intervalo [a, b]
            % y - valores de f(x) nos pontos de avaliação
            % dydx - valores aproximados
            x = a:h:b;
            n = length(x);
            if nargin == 5 %deteta o numero de argumentos na funçao
                y = f(x);  %se nao recebermos y, y passa a ser igual a f(x)
            end
           
                dydx = zeros(1,n);
                for k = 1:n-2
                   dydx(k) = (-3*y(k)+4*y(k+1)-y(k+2))/(2*h); %utilizando o metedo das regressivas
                end
                dydx(n-1)=(y(n-3)-4*y(n-2)+3*y(n-1))/(2*h);
                dydx(n) = (y(n-2)-4*y(n-1)+3*y(n))/(2*h);

        end

        function [x,y,dydx] = NDerivadaDF3(~,f,a,b,h,y)
            % Função NDerivadaDF3 para calcular a diferenças finitas 3 pontos
            % Entrada:
            % f - função a ser derivada
            % a - limite inferior do intervalo
            % b - limite superior do intervalo
            % h - passo
            % y - valores da função (opcional)
            % Saída:
            % x - pontos de avaliação no intervalo [a, b]
            % y - valores de f(x) nos pontos de avaliação
            % dydx - valores aproximados
            x = a:h:b;
            n = length(x);
            if nargin == 5 %deteta o numero de argumentos na funçao
                y = f(x);  %se nao recebermos y, y passa a ser igual a f(x)
            end
             dydx = zeros(1,n);
             dydx(1) = (y(2)-y(1))/h;
             for k = 2:n-1
                dydx(k) = (y(k)-y(k-1))/h; %utilizando o metedo das regressivas
             end  
             dydx(n) = (y(n)-y(n-1))/h;
        end

        function [x, y, dydx] = NDerivada2DF3(~, f, a, b, h, y)
            % Função NDerivada2DF3 para calcular a segunda derivada utilizando diferenças finitas centradas
            % Entrada:
            % f - função a ser derivada
            % a - limite inferior do intervalo
            % b - limite superior do intervalo
            % h - passo
            % y - valores da função (opcional)
            % Saída:
            % x - pontos de avaliação no intervalo [a, b]
            % y - valores de f(x) nos pontos de avaliação
            % dydx - valores aproximados

            x = a:h:b;
            n = length(x);

            if nargin == 5
                y = f(x);
            end
            dydx = zeros(1, n);
            % Calcula a derivada usando a fórmula centrada
            dydx(1) = (y(3)-2*y(2)+y(1))/(h^2); 
            for k = 2:n-1
                dydx(k) = (y(k+1)-2*y(k)+y(k-1))/(h^2);
            end
            dydx(n) = (y(n)-2*y(n-1)+y(n-2))/(h^2); 
        end


        function [x,s,out_S] = NIntegralS(~,f, a, b, n)
            % Função NIntegralS calcula o integral de f(x) no intervalo [a, b] usando a regra de Simpsons
            % Entrada:
            % f - função a ser integrada
            % a - limite inferior do intervalo
            % b - limite superior do intervalo
            % n - número de subintervalos
            % Saída:
            % x - pontos de avaliação no intervalo [a, b]
            % y - valores de f(x) nos pontos de avaliação
            % Out_S - valor aproximado do integral
            h = (b-a)/n;
            x = a;
            s = 0;
            for i = 1:n-1 
                   x = x + h;
                   if (mod(i, 2) == 0) %mod retorna o resto da divisao de i por 2
                       s = s + 2*f(x);
                   else 
                       s = s + 4*f(x);
                    end
                out_S = h/3*(f(a)+s+f(b))
            end
        end
        
        function [x,s,T] = NIntegralT(~,f, a, b, n)
            % Função NIntegralT calcula a integral de f(x) no intervalo [a, b] usando a regra dos trapézios
            % Entrada:
            % f - função a ser integrada
            % a - limite inferior do intervalo
            % b - limite superior do intervalo
            % n - número de subintervalos
            % Saída:
            % x - pontos de avaliação no intervalo [a, b]
            % y - valores de f(x) nos pontos de avaliação
            % T - valor aproximado do integral

            h = (b - a) / n;
            x = a;
            s = 0;
            for i = 1:n-1
                x = x + h;
                s = s + f(x);
            end
            T = h / 2 * (f(a) + 2 * s + f(b));

        end

        % Função para converter coordenadas Cartesiano para Cilíndrico
        function [rho, phi, z] = caToCi(~,x, y, z)
            rho = sqrt(x^2 + y^2);
            phi = atan2(y, x);
        end

        % Função para converter coordenadas Cartesiano para Esférico
        function [r, theta, phi] = caToEs(~,x, y, z)
            r = sqrt(x^2 + y^2 + z^2);
            theta = acos(z / r);
            phi = atan2(y, x);
        end

        % Função para converter coordenadas Cilíndrico para Cartesiano
        function [x, y, z] = ciToCa(~,rho, phi, z)
            x = rho * cos(phi);
            y = rho * sin(phi);
        end

        % Função para converter coordenadas Cilíndrico para Esférico
        function [r, theta, phi] = ciToEs(~,rho, phi, z)
            r = sqrt(rho^2 + z^2);
            theta = atan2(rho, z);
        end

        % Função para converter coordenadas Esférico para Cartesiano
        function [x, y, z] = esToCa(~,r, theta, phi)
            x = r * sin(phi) * cos(theta);
            y = r * sin(phi) * sin(theta);
            z = r * cos(phi);
        end

        % Função para converter coordenadas Esférico para Cilíndrico
        function [rho, theta, z] = esToCi(~,r, theta, phi)
            rho = r * sin(phi);
            z = r * cos(phi);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.IntegracaoNumerica.Visible="off";
            app.IntegraoNumricaListBoxLabel.Text = '';
            app.nSpinner.Visible="off";
            app.RP.Visible="off";
            app.transCoord.Text = "Tranforme suas Coordenadas";
            app.RP.Text = sprintf('Integral aproximado: 0 \n Integral Exato: 0 \n Erro: 0');

        end

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            
            syms z(x,y)
            
            % Leitura dos dados
            strF = app.eFxy.Value;
            f =@(x,y) eval(vectorize(strF));
            a = app.eA.Value; 
            h1 = app.eH1.Value; 
            b = app.eB.Value;
            c = app.eC.Value; 
            h2 = app.eH2.Value; 
            d = app.eD.Value;

            errID = "MATLAB:Executar:badFxy";
            msgtext = "Introduza uma função nas variáveis x e y";
            erroFxy = MException(errID,msgtext);
            % outras
            
            try
                try
                    z = f(x,y);
                    app.eFxy.BackgroundColor = "w";
                catch
                    app.eFxy.BackgroundColor = "r";
                    throw(erroFxy)
                end

                if (isscalar(b) && b<a)
                    errordlg("Valores do intervalo [a,b], em que b tem de ser maior que a","Erro detetado", "modal");
                    app.eA.BackgroundColor = [1,0,0];
                    app.eA.FontColor = [1,1,1]; 
                else
                    app.eA.BackgroundColor = [1,1,1];
                    app.eA.FontColor = [0,0,0]; 
                end

                if (isscalar(d) && d<c)
                    errordlg("Valores do intervalo [c,d], em que d tem de ser maior que c","Erro detetado", "modal");
                    app.eC.BackgroundColor = [1,0,0];
                    app.eC.FontColor = [1,1,1]; 
                else
                    app.eC.BackgroundColor = [1,1,1];
                    app.eC.FontColor = [0,0,0]; 
                end

                % Representação gráfica
                vx = a:h1:b;                % vetor das abcissas
                vy = c:h2:d;                % vetor das ordenadas
                [x,y] = meshgrid(vx,vy);    % malha de pontos a 2D
                z = f(x,y);                 % vetor das cotas
                surfc(app.UIAxes,x,y,z)

                eq1 = "$\frac{\partial^2 f}{\partial x^2} + \frac{\partial^2 f}{\partial y^2} = 0$";
                eq2 = "$\frac{\partial^2 f}{\partial x^2} + \frac{\partial^2 f}{\partial y^2} \neq 0$";

                if isHarmonica(app,f)
                    textoHarmonica = "A função é harmónica";
                    % corHarmonica = "g";
                    corHarmonica = [0 0.5 0]; % darkgreen
                    app.LabelLaplaceEDP.Text = eq1;
                else
                    textoHarmonica = "A função não é harmónica";
                    corHarmonica = "r";
                    app.LabelLaplaceEDP.Text = eq2;
                end
                app.LabelHarmonica.Text = textoHarmonica;
                app.LabelHarmonica.FontWeight = "bold";
                app.LabelHarmonica.FontColor = corHarmonica;
                
            catch Me
                errordlg(Me.message,"ERRO","modal")
            end
        end

        % Button pushed function: ExecutarButton_2
        function ExecutarButton_2Pushed(app, event)
            syms y(x)
            strFx = app.eFx.Value;
            f=@(x) eval(vectorize(strFx));
            a = app.eA_2.Value; h = app.eH.Value; b = app.eB_2.Value; n=app.nSpinner.Value;

            try
                dydxSym = diff(f,x);
                dfdx = @(x) eval(vectorize(char(dydxSym)));

                dydxSym2 = diff(diff(f,x));
                dfdx2 = @(x) eval(vectorize(char(dydxSym2)));

                [x,y,dydxDFP] = NDerivadaDFP(app,f,a,b,h);
                [x,y,dydxDFR] = NDerivadaDFR(app,f,a,b,h);
                [x,y,dydxDF3] = NDerivadaDF3(app,f,a,b,h);
                [x,y,dydx2DF3] = NDerivada2DF3(app,f,a,b,h);
                [x,y,dydxDF3R] = NDerivadaDF3R(app,f,a,b,h);
                [x,y,dydxDF3C] = NDerivadaDF3C(app,f,a,b,h);
                [x,y,dydxDF3P] = NDerivadaDF3P(app,f,a,b,h);

                dydxExata = dfdx(x);
                erroDFP = abs(dydxExata-dydxDFP);
                erroDFR = abs(dydxExata-dydxDFR);
                erroDF3 = abs(dydxExata-dydxDF3);
                erro2DF3 = abs(dydxExata-dydx2DF3);
                erroDF3R = abs(dydxExata-dydxDF3R);
                erroDF3C = abs(dydxExata-dydxDF3C);
                erroDF3P = abs(dydxExata-dydxDF3P);

                if(app.rdDerivadas.Value == true)

                    if(app.rdDFP.Value == true)
                        tabelaResultados = ([x.',y.',dydxExata.',dydxDFP.',erroDFP.']);
                        plot(app.UIAxes2,x,y,"-r*")
                        hold(app.UIAxes2,"on")
                        plot(app.UIAxes2,x,dydxExata,"-bo")
                        plot(app.UIAxes2,x,dydxDFP,"-g+")
                        hold(app.UIAxes2,"off")
                        legend(app.UIAxes2,["f(x)","dydxExata","dydxDFP"])
                        app.UITable.Data = tabelaResultados;
                        app.UITable.ColumnName = ["x","y","dydxExata","dydxDFP","erroDFP"];
                    elseif (app.rdDFR.Value == true)
                        tabelaResultados = ([x.',y.',dydxExata.',dydxDFR.',erroDFR.']);
                        plot(app.UIAxes2,x,y,"-r*")
                        hold(app.UIAxes2,"on")
                        plot(app.UIAxes2,x,dydxExata,"-bo")
                        plot(app.UIAxes2,x,dydxDFR,"-g+")
                        hold(app.UIAxes2,"off")
                        legend(app.UIAxes2,["f(x)","dydxExata","dydxDFR"])
                        app.UITable.Data = tabelaResultados;
                        app.UITable.ColumnName = ["x","y","dydxExata","dydxDFR","erroDFR"];
                    elseif(app.rdDF3R.Value == true)
                        tabelaResultados = ([x.',y.',dydxExata.',dydxDF3R.',erroDF3R.']);
                        plot(app.UIAxes2,x,y,"-r*")
                        hold(app.UIAxes2,"on")
                        plot(app.UIAxes2,x,dydxExata,"-bo")
                        plot(app.UIAxes2,x,dydxDF3R,"-g+")
                        hold(app.UIAxes2,"off")
                        legend(app.UIAxes2,["f(x)","dydxExata","dydxDF3R"])
                        app.UITable.Data = tabelaResultados;
                        app.UITable.ColumnName = ["x","y","dydxExata","dydxDF3R","erroDF3R"];
                    elseif(app.rdDF3C.Value == true)
                        tabelaResultados = ([x.',y.',dydxExata.',dydxDF3C.',erroDF3C.']);
                        plot(app.UIAxes2,x,y,"-r*")
                        hold(app.UIAxes2,"on")
                        plot(app.UIAxes2,x,dydxExata,"-bo")
                        plot(app.UIAxes2,x,dydxDF3C,"-g+")
                        hold(app.UIAxes2,"off")
                        legend(app.UIAxes2,["f(x)","dydxExata","dydxDF3C"])
                        app.UITable.Data = tabelaResultados;
                        app.UITable.ColumnName = ["x","y","dydxExata","dydxDF3C","erroDF3C"];
                    elseif(app.rdDF3P.Value == true)
                        tabelaResultados = ([x.',y.',dydxExata.',dydxDF3P.',erroDF3P.']);
                        plot(app.UIAxes2,x,y,"-r*")
                        hold(app.UIAxes2,"on")
                        plot(app.UIAxes2,x,dydxExata,"-bo")
                        plot(app.UIAxes2,x,dydxDF3P,"-g+")
                        hold(app.UIAxes2,"off")
                        legend(app.UIAxes2,["f(x)","dydxExata","dydxDF3P"])
                        app.UITable.Data = tabelaResultados;
                        app.UITable.ColumnName = ["x","y","dydxExata","dydxDF3C","erroDF3C"];
                    elseif(app.rd2DF3p.Value == true)
                        dydxExata = dfdx2(x);
                        tabelaResultados = ([x.',y.',dydxExata.',dydx2DF3.',erro2DF3.']);
                        plot(app.UIAxes2,x,dydxExata,"-bo")
                        hold(app.UIAxes2,"on")
                        plot(app.UIAxes2,x,dydx2DF3,"-g+")
                        hold(app.UIAxes2,"off")
                        legend(app.UIAxes2,["f(x)","dydxExata","dydx2DF3"])
                        app.UITable.Data = tabelaResultados;
                        app.UITable.ColumnName = ["x","y","dydxExata","dydx2DF3","erro2DF3"];
                    end 

                elseif(app.rdPrimitivas.Value == true)

                    if(app.IntegracaoNumerica.Value == 1)
                        [x,y,T] = NIntegralT(app,f,a,b,n);
                        
                        app.RP.Text = sprintf('Integral aproximado: %.4f \n Integral Exato: %.4f \n Erro: %.4f', T, integral(f,a,b), abs(integral(f,a,b)-T));

                    elseif (app.IntegracaoNumerica.Value == 2)
                     [x,y,S] = NIntegralS(app,f,a,b,n);
                       
                        app.RP.Text = sprintf('Integral aproximado: %.4f \n Integral Exato: %.4f \n Erro: %.4f', S, integral(f,a,b), abs(integral(f,a,b)-S));
                       
                    end
                end

            catch Me
                errordlg(Me.message,"ERRO","modal")
            end
        end

        % Selection changed function: rdgCalculode
        function rdgCalculodeSelectionChanged(app, event)
            selectedButton = app.rdgCalculode.SelectedObject;  %consegue verificar qual o botao selecionado e altera o aspeto grafico
            
            if(selectedButton == app.rdPrimitivas)
    
                app.IntegracaoNumerica.Visible="on";   
                app.DerivacaoNumericaButtonGroup.Visible="off"
                app.eH.Visible="off";
                app.hLabel.Text="b";
                app.bLabel.Text="n";
                app.Label_8.Visible="off";
                app.nSpinner.Visible="on";
                app.eB_2.Position=[114,248,40,22]
                app.IntegraoNumricaListBoxLabel.Text = 'Integração Numérica';
                app.RP.Visible="on"

            elseif(selectedButton == app.rdDerivadas)

                app.DerivacaoNumericaButtonGroup.Visible="on"
                app.eH.Visible="on";
                app.hLabel.Text="h";
                app.bLabel.Text="";
                app.bLabel.Text="b";
                app.Label_8.Visible="on";
                app.nSpinner.Visible="off";
                app.eB_2.Position=[173,248,40,22];
                app.IntegraoNumricaListBoxLabel.Text = '';
                app.IntegracaoNumerica.Visible="off";
                app.RP.Visible="off"

            end
        end

        % Button pushed function: ExecutarButton_3
        function ExecutarButton_3Pushed(app, event)
        
        c1 = app.C1.Value; c2 = app.C2.Value; c3 = app.C3.Value;
        app.Lc1.Text = "x"; app.Lc2.Text = "y"; app.Lc3.Text = "z";
        
        if (app.rdE.Value == true)

            app.Lc1.Text = "r"; 
            app.Lc2.Text = "θ"; 
            app.Lc3.Text = "φ";

            [x, y, z1]= esToCa(app,c1,c2,c3);
            [rho, phi, z2]= esToCi(app,c1,c2,c3);

            exp1 = sprintf('Cartesianas (x, y, z) = (%.2f, %.2f, %.2f)', x, y, z1); % cria uma string deste tipo
            exp2 = sprintf('Cilíndricas (ρ, φ, z) = (%.2f, %.2f, %.2f)', rho, phi, z2);

        elseif app.rdCi.Value == true

            app.Lc1.Text = "ρ"; 
            app.Lc2.Text = "θ"; 
            app.Lc3.Text = "z";

            [x, y, z]= ciToCa(app,c1,c2,c3);
            [r, phi, theta]= ciToEs(app,c1,c2,c3);

            exp1 = sprintf('Cartesianas (x, y, z) = (%.2f, %.2f, %.2f)', x, y, z); % cria uma string deste tipo
            exp2 = sprintf('Esféricas (r, θ, φ) = (%.2f, %.2f, %.2f)', r, theta, phi);

        elseif app.rdCa.Value == true
            
            app.Lc1.Text = "x"; 
            app.Lc2.Text = "y"; 
            app.Lc3.Text = "z"; 

            [rho, phi1, z]= caToCi(app,c1,c2,c3);
            [r, phi2, theta]= caToEs(app,c1,c2,c3);


            exp1 = sprintf('Cilíndricas  (ρ, φ, z) = (%.2f, %.2f, %.2f)', rho, phi1, z); % cria uma string deste tipo
            exp2 = sprintf('Esféricas  (r, θ, φ) = (%.2f, %.2f, %.2f)', r, theta, phi2);

        end

        latexStr = sprintf('%s\n %s', exp1, exp2);  %concatena as duas strings 

        app.transCoord.Text = latexStr;

        end

        % Callback function
        function TCIButtonDown(app, event)

        end

        % Selection changed function: TCI
        function TCISelectionChanged(app, event)
            selectedButton = app.TCI.SelectedObject;

            if selectedButton == app.rdE

                app.Lc1.Text = "r";
                app.Lc2.Text = "θ";
                app.Lc3.Text = "φ";
                app.C1.Value = 2; app.C2.Value = pi/3; app.C3.Value = 5;

            elseif selectedButton == app.rdCi

                app.Lc1.Text = "ρ";
                app.Lc2.Text = "φ";
                app.Lc3.Text = "z";
                app.C1.Value = 6; app.C2.Value = pi/3; app.C3.Value = pi/6;

            elseif selectedButton == app.rdCa

                app.Lc1.Text = "x";
                app.Lc2.Text = "y";
                app.Lc3.Text = "z";
                app.C1.Value = 2; app.C2.Value = 3; app.C3.Value = 5;

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MaquinaCDIUIFigure = uifigure('Visible', 'off');
            app.MaquinaCDIUIFigure.Name = 'MaquinaCDI';
            app.TabGroup = uitabgroup(app.MaquinaCDIUIFigure);
            app.zfxyTab = uitab(app.TabGroup);
            app.zfxyTab.Title = ' z = f(x,y) ';
            app.UIAxes = uiaxes(app.zfxyTab);
            title(app.UIAxes, 'Superfície')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.zfxyTab);
            app.fxyEditFieldLabel = uilabel(app.Panel);
            app.fxyEditFieldLabel.Text = 'f(x,y) = ';
            app.eFxy = uieditfield(app.Panel, 'text');
            app.eFxy.Value = 'x^2+y^2';
            app.ExecutarButton = uibutton(app.Panel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.xLabel = uilabel(app.Panel);
            app.xLabel.Text = 'x = ';
            app.aEditFieldLabel = uilabel(app.Panel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.Panel, 'numeric');
            app.eA.Value = -2;
            app.Label = uilabel(app.Panel);
            app.Label.Text = ':';
            app.h1EditFieldLabel = uilabel(app.Panel);
            app.h1EditFieldLabel.Text = 'h1';
            app.eH1 = uieditfield(app.Panel, 'numeric');
            app.eH1.Value = 0.2;
            app.bEditFieldLabel = uilabel(app.Panel);
            app.bEditFieldLabel.Text = 'b';
            app.eB = uieditfield(app.Panel, 'numeric');
            app.eB.Value = 2;
            app.yLabel = uilabel(app.Panel);
            app.yLabel.Text = 'y = ';
            app.aEditFieldLabel_2 = uilabel(app.Panel);
            app.aEditFieldLabel_2.Text = 'c';
            app.h1EditFieldLabel_2 = uilabel(app.Panel);
            app.h1EditFieldLabel_2.Text = 'h2';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = ':';
            app.bEditFieldLabel_2 = uilabel(app.Panel);
            app.bEditFieldLabel_2.Text = 'd';
            app.eC = uieditfield(app.Panel, 'numeric');
            app.eC.Value = -2;
            app.eH2 = uieditfield(app.Panel, 'numeric');
            app.eH2.Value = 0.2;
            app.eD = uieditfield(app.Panel, 'numeric');
            app.eD.Value = 2;
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = ':';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = ':';
            app.LabelHarmonica = uilabel(app.Panel);
            app.LabelHarmonica.Text = 'A função não é harmónica';
            app.Panel_2 = uipanel(app.Panel);
            app.LabelLaplaceEDP = uilabel(app.Panel_2);
            app.LabelLaplaceEDP.Interpreter = 'latex';
            app.LabelLaplaceEDP.Text = '$\frac{\partial^2 f}{\partial x^2} + \frac{\partial^2 f}{\partial y^2} \neq 0$';
            app.yfxTab = uitab(app.TabGroup);
            app.yfxTab.Title = ' y = f(x) ';
            app.UIAxes2 = uiaxes(app.yfxTab);
            title(app.UIAxes2, 'Derivação Numérica')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Panel_3 = uipanel(app.yfxTab);
            app.ExecutarButton_2 = uibutton(app.Panel_3, 'push');
            app.ExecutarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButton_2Pushed, true);
            app.ExecutarButton_2.Text = 'Executar';
            app.xLabel_2 = uilabel(app.Panel_3);
            app.xLabel_2.Text = 'x = ';
            app.Label_7 = uilabel(app.Panel_3);
            app.Label_7.Text = ':';
            app.Label_8 = uilabel(app.Panel_3);
            app.Label_8.Text = ':';
            app.fxLabel = uilabel(app.Panel_3);
            app.fxLabel.Text = 'f(x) = ';
            app.eFx = uieditfield(app.Panel_3, 'text');
            app.eFx.Value = 'sin(x)';
            app.aEditFieldLabel_3 = uilabel(app.Panel_3);
            app.aEditFieldLabel_3.Text = 'a';
            app.eA_2 = uieditfield(app.Panel_3, 'numeric');
            app.eH = uieditfield(app.Panel_3, 'numeric');
            app.eH.Limits = [0 Inf];
            app.eH.Value = 0.2;
            app.eB_2 = uieditfield(app.Panel_3, 'numeric');
            app.eB_2.Value = 7;
            app.DerivacaoNumericaButtonGroup = uibuttongroup(app.Panel_3);
            app.DerivacaoNumericaButtonGroup.Title = 'Derivação Numérica';
            app.rdDFP = uiradiobutton(app.DerivacaoNumericaButtonGroup);
            app.rdDFP.Text = 'Diferenças Finitas Progressivas';
            app.rdDFP.Value = true;
            app.rdDFR = uiradiobutton(app.DerivacaoNumericaButtonGroup);
            app.rdDFR.Text = 'Diferenças Finitas Regressivas';
            app.rdDF3R = uiradiobutton(app.DerivacaoNumericaButtonGroup);
            app.rdDF3R.Text = 'Dif. Finitas em 3 pontos Regressiva';
            app.rd2DF3p = uiradiobutton(app.DerivacaoNumericaButtonGroup);
            app.rd2DF3p.Text = '2ª Derivadas Finitas Prog. 3 pontos';
            app.rdDF3C = uiradiobutton(app.DerivacaoNumericaButtonGroup);
            app.rdDF3C.Text = 'Dif. Finitas em 3 pontos Centrada';
            app.rdDF3P = uiradiobutton(app.DerivacaoNumericaButtonGroup);
            app.rdDF3P.Text = 'Dif. Finitas em 3 pontos Progressiva';
            app.rdgCalculode = uibuttongroup(app.Panel_3);
            app.rdgCalculode.SelectionChangedFcn = createCallbackFcn(app, @rdgCalculodeSelectionChanged, true);
            app.rdgCalculode.Title = 'Cálculo de';
            app.rdDerivadas = uiradiobutton(app.rdgCalculode);
            app.rdDerivadas.Text = 'Derivadas';
            app.rdDerivadas.Value = true;
            app.rdPrimitivas = uiradiobutton(app.rdgCalculode);
            app.rdPrimitivas.Text = 'Primitivas';
            app.IntegraoNumricaListBoxLabel = uilabel(app.Panel_3);
            app.IntegraoNumricaListBoxLabel.Visible = 'off';
            app.IntegraoNumricaListBoxLabel.Text = 'Integração Numérica';
            app.IntegracaoNumerica = uilistbox(app.Panel_3);
            app.IntegracaoNumerica.Items = {'Regra dos Trapézios', 'Regra de Simpson'};
            app.IntegracaoNumerica.ItemsData = [1 2];
            app.IntegracaoNumerica.Value = 1;
            app.hLabel = uilabel(app.Panel_3);
            app.hLabel.Text = 'h';
            app.bLabel = uilabel(app.Panel_3);
            app.bLabel.Text = 'b';
            app.nSpinner = uispinner(app.Panel_3);
            app.nSpinner.Limits = [1 Inf];
            app.nSpinner.Value = 10;
            app.RP = uilabel(app.Panel_3);
            app.RP.Text = 'Integral aproximada:';
            app.UITable = uitable(app.yfxTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.CoordenadasTab = uitab(app.TabGroup);
            app.CoordenadasTab.Title = 'Coordenadas';
            app.TransformaodecoordenadasPanel = uipanel(app.CoordenadasTab);
            app.TransformaodecoordenadasPanel.Title = 'Transformação de coordenadas';
            app.TCI = uibuttongroup(app.TransformaodecoordenadasPanel);
            app.TCI.SelectionChangedFcn = createCallbackFcn(app, @TCISelectionChanged, true);
            app.TCI.Title = 'Tipo de coordenadas a introduzir';
            app.rdCa = uiradiobutton(app.TCI);
            app.rdCa.Text = 'Cartesianas';
            app.rdCa.Value = true;
            app.rdCi = uiradiobutton(app.TCI);
            app.rdCi.Text = 'Cilíndricas';
            app.rdE = uiradiobutton(app.TCI);
            app.rdE.Text = 'Esféricas';
            app.ExecutarButton_3 = uibutton(app.TransformaodecoordenadasPanel, 'push');
            app.ExecutarButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButton_3Pushed, true);
            app.ExecutarButton_3.Text = 'Executar';
            app.C1 = uieditfield(app.TransformaodecoordenadasPanel, 'numeric');
            app.C1.Value = -2;
            app.C2 = uieditfield(app.TransformaodecoordenadasPanel, 'numeric');
            app.C2.Value = 0.2;
            app.C3 = uieditfield(app.TransformaodecoordenadasPanel, 'numeric');
            app.C3.Value = 2;
            app.Lc1 = uilabel(app.TransformaodecoordenadasPanel);
            app.Lc1.Text = 'x';
            app.Lc2 = uilabel(app.TransformaodecoordenadasPanel);
            app.Lc2.Text = 'y';
            app.Lc3 = uilabel(app.TransformaodecoordenadasPanel);
            app.Lc3.Text = 'z';
            app.transCoord = uilabel(app.CoordenadasTab);
            app.AutoresTab = uitab(app.TabGroup);
            app.AutoresTab.Title = 'Autores';
            app.GridLayout = uigridlayout(app.AutoresTab);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Imagens', 'armenioc.jpg');
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Imagens', 'manuel.jpg');
            app.Image3 = uiimage(app.GridLayout);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Imagens', 'tiagof.jpg');
            app.ArmnioCorreiaLabel = uilabel(app.GridLayout);
            app.ArmnioCorreiaLabel.Text = 'Arménio Correia';
            app.ManuelFurtadoLabel = uilabel(app.GridLayout);
            app.ManuelFurtadoLabel.Text = 'Manuel Furtado';
            app.TiagoFilipeLabel = uilabel(app.GridLayout);
            app.TiagoFilipeLabel.Text = 'Tiago Filipe';
            % Show the figure after all components are created
            app.MaquinaCDIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Maquina03CDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MaquinaCDIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaquinaCDIUIFigure)
        end
    end
end