classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        harmonica              matlab.ui.control.Label
        EDP                    matlab.ui.control.Label
        ExecutarButton         matlab.ui.control.Button
        passoEditField_2       matlab.ui.control.NumericEditField
        passoEditField_2Label  matlab.ui.control.Label
        dEditField             matlab.ui.control.NumericEditField
        dEditFieldLabel        matlab.ui.control.Label
        cEditField             matlab.ui.control.NumericEditField
        cEditFieldLabel        matlab.ui.control.Label
        IntervaloparayLabel    matlab.ui.control.Label
        passoEditField         matlab.ui.control.NumericEditField
        passoEditFieldLabel    matlab.ui.control.Label
        bEditField             matlab.ui.control.NumericEditField
        bEditFieldLabel        matlab.ui.control.Label
        aEditField             matlab.ui.control.NumericEditField
        aEditFieldLabel        matlab.ui.control.Label
        IntervaloparaxLabel    matlab.ui.control.Label
        zfxyEditField          matlab.ui.control.EditField
        zfxyEditFieldLabel     matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function isHarmonica = isHarmonica(~,f)
            
            syms x y 

            if diff(f,x,2) + diff(f,y,2) == 0
                isHarmonica = 1;
            else
                isHarmonica = 0;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            strF=app.zfxyEditField.Value;
            f = @(x,y)eval(vectorize(strF));
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            h_x = app.passoEditField.Value;
            c = app.cEditField.Value;
            d = app.dEditField.Value;
            h_y = app.passoEditField_2.Value;
           
            x=a:h_y:b;
            y=c:h_y:d;
           
            [X,Y]=meshgrid(x,y);

            z=f(X,Y);
            surfc(app.UIAxes,x,y,z);

            if isHarmonica(app,f)
                app.EDP.Text = '$\frac{\partial^2 z}{\partial x^2} + \frac{\partial^2 z}{\partial y^2} = 0$';
                app.harmonica.Text = 'A funçao é armonica';
                app.harmonica.FontColor = [0.4667 0.6745 0.1882];
            else
                app.EDP.Text = '$\frac{\partial^2 z}{\partial x^2} + \frac{\partial^2 z}{\partial y^2} \neq 0$';
                app.harmonica.Text = 'A funçao não é armonica';
                app.harmonica.FontColor = [1 0 0.1882];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.zfxyEditFieldLabel = uilabel(app.UIFigure);
            app.zfxyEditFieldLabel.Text = 'z = f(x,y)';
            app.zfxyEditField = uieditfield(app.UIFigure, 'text');
            app.zfxyEditField.Value = 'x^2-y^2';
            app.IntervaloparaxLabel = uilabel(app.UIFigure);
            app.IntervaloparaxLabel.Text = 'Intervalo para x';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.Value = -4;
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.Value = 4;
            app.passoEditFieldLabel = uilabel(app.UIFigure);
            app.passoEditFieldLabel.Text = 'passo';
            app.passoEditField = uieditfield(app.UIFigure, 'numeric');
            app.passoEditField.Value = 1;
            app.IntervaloparayLabel = uilabel(app.UIFigure);
            app.IntervaloparayLabel.Text = 'Intervalo para y';
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.cEditField.Value = -4;
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.UIFigure, 'numeric');
            app.dEditField.Value = 4;
            app.passoEditField_2Label = uilabel(app.UIFigure);
            app.passoEditField_2Label.Text = 'passo';
            app.passoEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.passoEditField_2.Value = 1;
            app.ExecutarButton = uibutton(app.UIFigure, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.EDP = uilabel(app.UIFigure);
            app.EDP.Interpreter = 'latex';
            app.EDP.Text = '$\frac{\partial^2 z}{\partial x^2} + \frac{\partial^2 z}{\partial y^2} = 0$';
            app.harmonica = uilabel(app.UIFigure);
            app.harmonica.Text = 'A funçao é armonica';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end