classdef Maquina02CDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaquinaCDIUIFigure  matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        zfxyTab             matlab.ui.container.Tab
        Panel               matlab.ui.container.Panel
        LabelHarmonica      matlab.ui.control.Label
        eqHarmonica         matlab.ui.control.Label
        Label_6             matlab.ui.control.Label
        Label_5             matlab.ui.control.Label
        eD                  matlab.ui.control.NumericEditField
        eH2                 matlab.ui.control.NumericEditField
        eC                  matlab.ui.control.NumericEditField
        bEditFieldLabel_2   matlab.ui.control.Label
        Label_4             matlab.ui.control.Label
        h1EditFieldLabel_2  matlab.ui.control.Label
        aEditFieldLabel_2   matlab.ui.control.Label
        yLabel              matlab.ui.control.Label
        eB                  matlab.ui.control.NumericEditField
        bEditFieldLabel     matlab.ui.control.Label
        eH1                 matlab.ui.control.NumericEditField
        h1EditFieldLabel    matlab.ui.control.Label
        Label               matlab.ui.control.Label
        eA                  matlab.ui.control.NumericEditField
        aEditFieldLabel     matlab.ui.control.Label
        xLabel              matlab.ui.control.Label
        ExecutarButton      matlab.ui.control.Button
        eFxy                matlab.ui.control.EditField
        fxyEditFieldLabel   matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
        Tab2                matlab.ui.container.Tab
    end

    
    methods (Access = public)
        %funçao que testa se a funçao f é harmonica
        function YN = isHarmonica(~,f)
            
            syms z(x,y)

            if (simplify(diff(f,x,2) + diff(f,y,2)) == 0) %para que log(sqrt(x^2+y^2)) seja harmonica usamos o simplify
                YN=1;
            else
                YN=0;
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            
            syms z(x,y)
            
            % Leitura dos dados
            strF = app.eFxy.Value;
            f =@(x,y) eval(vectorize(strF));
            a = app.eA.Value; 
            h1 = app.eH1.Value; 
            b = app.eB.Value;
            c = app.eC.Value; 
            h2 = app.eH2.Value; 
            d = app.eD.Value;
            
            errID = "MATLAB:Executar:badFxy";
            msgtext = "Introduza uma função nas variáveis x e y";
            erroFuncao = MException(errID,msgtext);
            % outras exceções

            try
                
                try
	                z = f(x,y);
                    app.eFxy.BackgroundColor = "w";
                catch
                    app.eFxy.BackgroundColor = [1,0,0];
              
                    throw(erroFuncao);
                end 

                % Representação gráfica
                vx = a:h1:b;                % vetor das abcissas
                vy = c:h2:d;                % vetor das ordenadas
                [x,y] = meshgrid(vx,vy);    % malha de pontos a 2D
                z = f(x,y);                 % vetor das cotas
                surfc(app.UIAxes,x,y,z)
              
                %testar se aa funçao e harmonica

                if isHarmonica(app,f) 
                     app.eqHarmonica.Text = '$\frac{\partial^2 z}{\partial x^2} + \frac{\partial^2 z}{\partial y^2} = 0$';
                     textoHarmonica="Afunção é harmónica";
                     corHarmonica=[0 0.5 0];
                else
                    app.eqHarmonica.Text='$\frac{\partial^2 z}{\partial x^2} + \frac{\partial^2 z}{\partial y^2} \neq 0$';
                    textoHarmonica="Afunção não é harmónica";
                    corHarmonica="r";
                end

            app.LabelHarmonica.Text=textoHarmonica;
            app.LabelHarmonica.FontColor=corHarmonica;
            app.LabelHarmonica.FontWeight= "bold";
            app.LabelHarmonica.FontSize=13;

            catch Me
                 errordlg(Me.message,"Erro","modal");
          end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MaquinaCDIUIFigure = uifigure('Visible', 'off');
            app.MaquinaCDIUIFigure.Name = 'MaquinaCDI';
            app.TabGroup = uitabgroup(app.MaquinaCDIUIFigure);
            app.zfxyTab = uitab(app.TabGroup);
            app.zfxyTab.Title = ' z = f(x,y) ';
            app.UIAxes = uiaxes(app.zfxyTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.zfxyTab);
            app.fxyEditFieldLabel = uilabel(app.Panel);
            app.fxyEditFieldLabel.Text = 'f(x,y) = ';
            app.eFxy = uieditfield(app.Panel, 'text');
            app.eFxy.Value = 'x^2+y^2';
            app.ExecutarButton = uibutton(app.Panel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.xLabel = uilabel(app.Panel);
            app.xLabel.Text = 'x = ';
            app.aEditFieldLabel = uilabel(app.Panel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.Panel, 'numeric');
            app.eA.Value = -2;
            app.Label = uilabel(app.Panel);
            app.Label.Text = ':';
            app.h1EditFieldLabel = uilabel(app.Panel);
            app.h1EditFieldLabel.Text = 'h1';
            app.eH1 = uieditfield(app.Panel, 'numeric');
            app.eH1.Value = 0.2;
            app.bEditFieldLabel = uilabel(app.Panel);
            app.bEditFieldLabel.Text = 'b';
            app.eB = uieditfield(app.Panel, 'numeric');
            app.eB.Value = 2;
            app.yLabel = uilabel(app.Panel);
            app.yLabel.Text = 'y = ';
            app.aEditFieldLabel_2 = uilabel(app.Panel);
            app.aEditFieldLabel_2.Text = 'c';
            app.h1EditFieldLabel_2 = uilabel(app.Panel);
            app.h1EditFieldLabel_2.Text = 'h2';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = ':';
            app.bEditFieldLabel_2 = uilabel(app.Panel);
            app.bEditFieldLabel_2.Text = 'd';
            app.eC = uieditfield(app.Panel, 'numeric');
            app.eC.Value = -2;
            app.eH2 = uieditfield(app.Panel, 'numeric');
            app.eH2.Value = 0.2;
            app.eD = uieditfield(app.Panel, 'numeric');
            app.eD.Value = 2;
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = ':';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = ':';
            app.eqHarmonica = uilabel(app.Panel);
            app.eqHarmonica.Interpreter = 'latex';
            app.eqHarmonica.Text = '$\frac{\partial^2 z}{\partial x^2} + \frac{\partial^2 z}{\partial y^2} \neq  0$';
            app.LabelHarmonica = uilabel(app.Panel);
            app.LabelHarmonica.Text = 'A função não é harmonica';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.MaquinaCDIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Maquina02CDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MaquinaCDIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaquinaCDIUIFigure)
        end
    end
end