classdef Maquina03CDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaquinaCDIUIFigure            matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        zfxyTab                       matlab.ui.container.Tab
        Panel                         matlab.ui.container.Panel
        Panel_2                       matlab.ui.container.Panel
        LabelLaplaceEDP               matlab.ui.control.Label
        LabelHarmonica                matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        eD                            matlab.ui.control.NumericEditField
        eH2                           matlab.ui.control.NumericEditField
        eC                            matlab.ui.control.NumericEditField
        bEditFieldLabel_2             matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        h1EditFieldLabel_2            matlab.ui.control.Label
        aEditFieldLabel_2             matlab.ui.control.Label
        yLabel                        matlab.ui.control.Label
        eB                            matlab.ui.control.NumericEditField
        bEditFieldLabel               matlab.ui.control.Label
        eH1                           matlab.ui.control.NumericEditField
        h1EditFieldLabel              matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        eA                            matlab.ui.control.NumericEditField
        aEditFieldLabel               matlab.ui.control.Label
        xLabel                        matlab.ui.control.Label
        ExecutarButton                matlab.ui.control.Button
        eFxy                          matlab.ui.control.EditField
        fxyEditFieldLabel             matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        yfxTab                        matlab.ui.container.Tab
        Panel_3                       matlab.ui.container.Panel
        DerivacaoNumericaButtonGroup  matlab.ui.container.ButtonGroup
        DiferencasFinitasem3pontosButton  matlab.ui.control.RadioButton
        DiferencasFinitasRegressivasButton  matlab.ui.control.RadioButton
        rdDFP                         matlab.ui.control.RadioButton
        eB_2                          matlab.ui.control.NumericEditField
        bEditFieldLabel_3             matlab.ui.control.Label
        eH                            matlab.ui.control.NumericEditField
        hLabel                        matlab.ui.control.Label
        eA_2                          matlab.ui.control.NumericEditField
        aEditFieldLabel_3             matlab.ui.control.Label
        eFx                           matlab.ui.control.EditField
        fxLabel                       matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        xLabel_2                      matlab.ui.control.Label
        ExecutarButton_2              matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        % Teste se uma função é Harmónica
        function YN = isHarmonica(~,f)
            syms z(x,y)
            if (simplify(diff(f,x,2)+diff(diff(f,y),y))==0)
                YN = 1;
            else
                YN = 0;
            end          
        end
        
        function [x,y,dydx] = NDerivadaDFP(~,f,a,b,h,y)
            x = a:h:b;
            n = length(x);
            if nargin == 5 %deteta o numero de argumentos na funçao
                y = f(x);  %se nao recebermos y, y passa a ser igual a f(x)
            end
            dydx = zeros(1,n);
            for k = 1:n-1
                dydx(k) = (y(k+1)-y(k))/h; %utilizando o metedo das progressivas 
            end                            %valor do ponto - o proximo ponto a dividir por h = derivada
            dydx(n) = (y(n)-y(n-1))/h; %ultimo ponto usa o metodo da regressiva
            %dydx(n) = dydx(n-1)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            
            syms z(x,y)
            
            % Leitura dos dados
            strF = app.eFxy.Value;
            f =@(x,y) eval(vectorize(strF));
            a = app.eA.Value; 
            h1 = app.eH1.Value; 
            b = app.eB.Value;
            c = app.eC.Value; 
            h2 = app.eH2.Value; 
            d = app.eD.Value;

            errID = "MATLAB:Executar:badFxy";
            msgtext = "Introduza uma função nas variáveis x e y";
            erroFxy = MException(errID,msgtext);
            % outras
            
            try
                try
                    z = f(x,y);
                    app.eFxy.BackgroundColor = "w";
                catch
                    app.eFxy.BackgroundColor = "r";
                    throw(erroFxy)
                end

                % Representação gráfica
                vx = a:h1:b;                % vetor das abcissas
                vy = c:h2:d;                % vetor das ordenadas
                [x,y] = meshgrid(vx,vy);    % malha de pontos a 2D
                z = f(x,y);                 % vetor das cotas
                surfc(app.UIAxes,x,y,z)

                eq1 = "$\frac{\partial^2 f}{\partial x^2} + \frac{\partial^2 f}{\partial y^2} = 0$";
                eq2 = "$\frac{\partial^2 f}{\partial x^2} + \frac{\partial^2 f}{\partial y^2} \neq 0$";

                if isHarmonica(app,f)
                    textoHarmonica = "A função é harmónica";
                    % corHarmonica = "g";
                    corHarmonica = [0 0.5 0]; % darkgreen
                    app.LabelLaplaceEDP.Text = eq1;
                else
                    textoHarmonica = "A função não é harmónica";
                    corHarmonica = "r";
                    app.LabelLaplaceEDP.Text = eq2;
                end
                app.LabelHarmonica.Text = textoHarmonica;
                app.LabelHarmonica.FontWeight = "bold";
                app.LabelHarmonica.FontColor = corHarmonica;
                
            catch Me
                errordlg(Me.message,"ERRO","modal")
            end
        end

        % Button pushed function: ExecutarButton_2
        function ExecutarButton_2Pushed(app, event)
            syms y(x)
            strFx = app.eFx.Value;
            f=@(x) eval(vectorize(strFx));
            a = app.eA_2.Value; h = app.eH.Value; b = app.eB_2.Value;
            try
                dydxSym = diff(f,x);
                dfdx = @(x) eval(vectorize(char(dydxSym)));

                [x,y,dydxDFP] = NDerivadaDFP(app,f,a,b,h);
                dydxExata = dfdx(x);
                erroDFP = abs(dydxExata-dydxDFP);

                tabelaResultados = table([x.',y.',dydxExata.',dydxDFP.',erroDFP.']);%inserir a tabela por baixo do grafico
                plot(app.UIAxes2,x,y,"-r*")
                hold(app.UIAxes2,"on")
                plot(app.UIAxes2,x,dydxExata,"-bo")
                plot(app.UIAxes2,x,dydxDFP,"-g+")
                hold(app.UIAxes2,"off")
                legend(app.UIAxes2,["f(x)","dydxExata","dydxDFP"])

            catch Me
                errordlg(Me.message,"ERRO","modal")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MaquinaCDIUIFigure = uifigure('Visible', 'off');
            app.MaquinaCDIUIFigure.Name = 'MaquinaCDI';
            app.TabGroup = uitabgroup(app.MaquinaCDIUIFigure);
            app.zfxyTab = uitab(app.TabGroup);
            app.zfxyTab.Title = ' z = f(x,y) ';
            app.UIAxes = uiaxes(app.zfxyTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.zfxyTab);
            app.fxyEditFieldLabel = uilabel(app.Panel);
            app.fxyEditFieldLabel.Text = 'f(x,y) = ';
            app.eFxy = uieditfield(app.Panel, 'text');
            app.eFxy.Value = 'x^2+y^2';
            app.ExecutarButton = uibutton(app.Panel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.xLabel = uilabel(app.Panel);
            app.xLabel.Text = 'x = ';
            app.aEditFieldLabel = uilabel(app.Panel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.Panel, 'numeric');
            app.eA.Value = -2;
            app.Label = uilabel(app.Panel);
            app.Label.Text = ':';
            app.h1EditFieldLabel = uilabel(app.Panel);
            app.h1EditFieldLabel.Text = 'h1';
            app.eH1 = uieditfield(app.Panel, 'numeric');
            app.eH1.Value = 0.2;
            app.bEditFieldLabel = uilabel(app.Panel);
            app.bEditFieldLabel.Text = 'b';
            app.eB = uieditfield(app.Panel, 'numeric');
            app.eB.Value = 2;
            app.yLabel = uilabel(app.Panel);
            app.yLabel.Text = 'y = ';
            app.aEditFieldLabel_2 = uilabel(app.Panel);
            app.aEditFieldLabel_2.Text = 'c';
            app.h1EditFieldLabel_2 = uilabel(app.Panel);
            app.h1EditFieldLabel_2.Text = 'h2';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = ':';
            app.bEditFieldLabel_2 = uilabel(app.Panel);
            app.bEditFieldLabel_2.Text = 'd';
            app.eC = uieditfield(app.Panel, 'numeric');
            app.eC.Value = -2;
            app.eH2 = uieditfield(app.Panel, 'numeric');
            app.eH2.Value = 0.2;
            app.eD = uieditfield(app.Panel, 'numeric');
            app.eD.Value = 2;
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = ':';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = ':';
            app.LabelHarmonica = uilabel(app.Panel);
            app.LabelHarmonica.Text = 'A função não é harmónica';
            app.Panel_2 = uipanel(app.Panel);
            app.LabelLaplaceEDP = uilabel(app.Panel_2);
            app.LabelLaplaceEDP.Interpreter = 'latex';
            app.LabelLaplaceEDP.Text = '$\frac{\partial^2 f}{\partial x^2} + \frac{\partial^2 f}{\partial y^2} \neq 0$';
            app.yfxTab = uitab(app.TabGroup);
            app.yfxTab.Title = ' y = f(x) ';
            app.UIAxes2 = uiaxes(app.yfxTab);
            title(app.UIAxes2, 'Derivação Numérica')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Panel_3 = uipanel(app.yfxTab);
            app.ExecutarButton_2 = uibutton(app.Panel_3, 'push');
            app.ExecutarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButton_2Pushed, true);
            app.ExecutarButton_2.Text = 'Executar';
            app.xLabel_2 = uilabel(app.Panel_3);
            app.xLabel_2.Text = 'x = ';
            app.Label_7 = uilabel(app.Panel_3);
            app.Label_7.Text = ':';
            app.Label_8 = uilabel(app.Panel_3);
            app.Label_8.Text = ':';
            app.fxLabel = uilabel(app.Panel_3);
            app.fxLabel.Text = 'f(x) = ';
            app.eFx = uieditfield(app.Panel_3, 'text');
            app.eFx.Value = 'sin(x)';
            app.aEditFieldLabel_3 = uilabel(app.Panel_3);
            app.aEditFieldLabel_3.Text = 'a';
            app.eA_2 = uieditfield(app.Panel_3, 'numeric');
            app.hLabel = uilabel(app.Panel_3);
            app.hLabel.Text = 'h';
            app.eH = uieditfield(app.Panel_3, 'numeric');
            app.eH.Value = 0.2;
            app.bEditFieldLabel_3 = uilabel(app.Panel_3);
            app.bEditFieldLabel_3.Text = 'b';
            app.eB_2 = uieditfield(app.Panel_3, 'numeric');
            app.eB_2.Value = 7;
            app.DerivacaoNumericaButtonGroup = uibuttongroup(app.Panel_3);
            app.DerivacaoNumericaButtonGroup.Title = 'Derivação Numérica';
            app.rdDFP = uiradiobutton(app.DerivacaoNumericaButtonGroup);
            app.rdDFP.Text = 'Diferenças Finitas Progressivas';
            app.rdDFP.Value = true;
            app.DiferencasFinitasRegressivasButton = uiradiobutton(app.DerivacaoNumericaButtonGroup);
            app.DiferencasFinitasRegressivasButton.Text = 'Diferenças Finitas Regressivas';
            app.DiferencasFinitasem3pontosButton = uiradiobutton(app.DerivacaoNumericaButtonGroup);
            app.DiferencasFinitasem3pontosButton.Text = 'Diferenças Finitas em 3 pontos';
            % Show the figure after all components are created
            app.MaquinaCDIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Maquina03CDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MaquinaCDIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaquinaCDIUIFigure)
        end
    end
end