classdef Maquina03MNED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaquinaMNEDUIFigure          matlab.ui.Figure
        AppMenu                      matlab.ui.container.Menu
        RelatorioMenu                matlab.ui.container.Menu
        PaginaMenu                   matlab.ui.container.Menu
        SairMenu                     matlab.ui.container.Menu
        SobreMenu                    matlab.ui.container.Menu
        AutoresMenu                  matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        ResetButton                  matlab.ui.control.Button
        ExecutarButton               matlab.ui.control.Button
        MetodosNumericosButtonGroup  matlab.ui.container.ButtonGroup
        TodososMetodosButton         matlab.ui.control.ToggleButton
        OutrosButton                 matlab.ui.control.ToggleButton
        RK4Button                    matlab.ui.control.ToggleButton
        EulerMButton                 matlab.ui.control.ToggleButton
        ODE45Button                  matlab.ui.control.ToggleButton
        RK2Button                    matlab.ui.control.ToggleButton
        EulerButton                  matlab.ui.control.ToggleButton
        DadosdoPVIPanel              matlab.ui.container.Panel
        nSpinner                     matlab.ui.control.Spinner
        nSpinnerLabel                matlab.ui.control.Label
        eY0                          matlab.ui.control.NumericEditField
        y0EditFieldLabel             matlab.ui.control.Label
        eB                           matlab.ui.control.NumericEditField
        bEditFieldLabel              matlab.ui.control.Label
        eA                           matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        eF                           matlab.ui.control.EditField
        ftyEditFieldLabel            matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButtonPushed(app, [])
        end

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            % Dados
            strF = app.eF.Value;
            f = @(t,y) eval(vectorize(strF));
            a = app.eA.Value;
            b = app.eB.Value;
            n = app.nSpinner.Value;
            y0 = app.eY0.Value;
            
            syms y(t);

            errID = "MATLAB:Executar:badF";
            msgtext = "Introduza uma função nas variáveis t e y";
            erroFuncao = MException(errID,msgtext);
            % outras exceções

            MNnumericosSel = app.MetodosNumericosButtonGroup.SelectedObject.Text;

            try
                
                try
	                z = f(t,y);
                    app.eF.BackgroundColor = [1,1,1];
                catch
                    app.eF.BackgroundColor = [1,0,0];
              
                    throw(erroFuncao);
                end 

                % solução exata do problema
                
                sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                % solução exata do problema (expressão numérica)
                g = @(t) eval(vectorize(char(sExata)));

                % resultados e tabela de resultados
                [t,yEuler] = NEuler(f,a,b,n,y0);
                [~,yRK2] = NRK2(f,a,b,n,y0);

                yExata = g(t);
                erroEuler = abs(yExata-yEuler);
                erroRK2 = abs(yExata-yRK2);

                plot(app.UIAxes,t,yExata,"-bo");
                hold(app.UIAxes,"on");

                switch MNnumericosSel
                    case "Euler"
                        plot(app.UIAxes,t,yEuler,"-g*");
                        legend(app.UIAxes,'Exata','Euler');

                        A = [t.',yExata.',yEuler.',erroEuler.'];
                        nomeColunas = ["t","Exata","Euler","erroEuler"];
                    case "RK2"
                        plot(app.UIAxes,t,yRK2,"-r+");
                        legend(app.UIAxes,'Exata',"RK2");

                        A = [t.',yExata.',yRK2.',erroRK2.'];
                        nomeColunas = ["t","Exata","RK2","erroRK2"];
                    case "Todos os Métodos"
                        plot(app.UIAxes,t,yEuler,"-g*");
                        plot(app.UIAxes,t,yRK2,"-r+");
                        legend(app.UIAxes,'Exata','Euler',"RK2");

                        A = [t.',yExata.',yEuler.',yRK2.',erroEuler.',erroRK2.'];
                        nomeColunas = ["t","Exata","Euler","RK2","erroEuler","erroRK2"];
                    otherwise
                        warndlg("Método não implementado","AVISO","modal");
                        
                        A = [];
                        nomeColunas = [];
                end

                hold(app.UIAxes,"off");
                grid(app.UIAxes,"on");

                tabelaResultados = array2table(A);
                app.UITable.Data = tabelaResultados;
                app.UITable.ColumnName = nomeColunas;

            catch Me
                errordlg(Me.message,"Erro","modal");
            end
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            Autores;
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?","Sair","Sim","Não","Sim");

            if strcmp(SN,"Sim")
                delete(app.MaquinaMNEDUIFigure)
            end
        end

        % Close request function: MaquinaMNEDUIFigure
        function MaquinaMNEDUIFigureCloseRequest(app, event)
            SairMenuSelected(app,event);
    
        end

        % Menu selected function: RelatorioMenu
        function RelatorioMenuSelected(app, event)
            open("FUC_AM2_LEI_2023-2024.pdf");
        end

        % Menu selected function: PaginaMenu
        function PaginaMenuSelected(app, event)
            web("www.isec.pt")
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MaquinaMNEDUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {415, 415};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {231, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MaquinaMNEDUIFigure = uifigure('Visible', 'off');
            app.MaquinaMNEDUIFigure.Name = 'MaquinaMNED';
            app.MaquinaMNEDUIFigure.CloseRequestFcn = createCallbackFcn(app, @MaquinaMNEDUIFigureCloseRequest, true);
            app.MaquinaMNEDUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.AppMenu = uimenu(app.MaquinaMNEDUIFigure);
            app.AppMenu.Text = 'App';
            app.RelatorioMenu = uimenu(app.AppMenu);
            app.RelatorioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatorioMenuSelected, true);
            app.RelatorioMenu.Text = 'Relatório';
            app.PaginaMenu = uimenu(app.AppMenu);
            app.PaginaMenu.MenuSelectedFcn = createCallbackFcn(app, @PaginaMenuSelected, true);
            app.PaginaMenu.Text = 'Página';
            app.SairMenu = uimenu(app.AppMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.SobreMenu = uimenu(app.MaquinaMNEDUIFigure);
            app.SobreMenu.Text = 'Sobre';
            app.AutoresMenu = uimenu(app.SobreMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.GridLayout = uigridlayout(app.MaquinaMNEDUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdoPVIPanel = uipanel(app.LeftPanel);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.ftyEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.ftyEditFieldLabel.Text = 'f(t,y) =';
            app.eF = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eF.Value = 'y+exp(3*t)';
            app.aEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eA.Value = 1;
            app.bEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.bEditFieldLabel.Text = 'b';
            app.eB = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB.Value = 1.5;
            app.y0EditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.y0EditFieldLabel.Text = 'y0';
            app.eY0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eY0.Value = 2;
            app.nSpinnerLabel = uilabel(app.DadosdoPVIPanel);
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.DadosdoPVIPanel);
            app.nSpinner.Limits = [1 Inf];
            app.nSpinner.Value = 2;
            app.MetodosNumericosButtonGroup = uibuttongroup(app.LeftPanel);
            app.MetodosNumericosButtonGroup.Title = 'Métodos Numéricos';
            app.EulerButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.EulerButton.Text = 'Euler';
            app.EulerButton.Value = true;
            app.RK2Button = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.RK2Button.Text = 'RK2';
            app.ODE45Button = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.ODE45Button.Text = 'ODE45';
            app.EulerMButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.EulerMButton.Text = 'Euler M';
            app.RK4Button = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.RK4Button.Text = 'RK4';
            app.OutrosButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.OutrosButton.Text = 'Outros';
            app.TodososMetodosButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.TodososMetodosButton.Text = 'Todos os Métodos';
            app.ExecutarButton = uibutton(app.LeftPanel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.MaquinaMNEDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Maquina03MNED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MaquinaMNEDUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaquinaMNEDUIFigure)
        end
    end
end