classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ConfigurareTreinarRedeTab     matlab.ui.container.Tab
        NmerodecamadasEditField       matlab.ui.control.NumericEditField
        NmerodecamadasEditFieldLabel  matlab.ui.control.Label
        FunodetreinoDropDown          matlab.ui.control.DropDown
        FunodetreinoDropDownLabel     matlab.ui.control.Label
        FunodeativaoDropDown          matlab.ui.control.DropDown
        FunodeativaoDropDownLabel     matlab.ui.control.Label
        valorestreinoGauge            matlab.ui.control.LinearGauge
        valorestreinoGaugeLabel       matlab.ui.control.Label
        carregarButton                matlab.ui.control.Button
        gravarButton                  matlab.ui.control.Button
        VisualizarResultadosButton    matlab.ui.control.Button
        TreinarRedeButton             matlab.ui.control.Button
        CriarRedeButton               matlab.ui.control.Button
        NeurniosporcamadaEditField    matlab.ui.control.NumericEditField
        NeurniosporcamadaEditFieldLabel  matlab.ui.control.Label
        ImgtesteTab                   matlab.ui.container.Tab
        ImagemDisplay                 matlab.ui.control.Image
        CarregardesenhoatestarButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        rede                            % Rede Neural
        dadosTreinamento                % Dados de Treinamento
        dadosTeste                      % Dados de Teste
        resultadosTreinamento           % Resultados do Treinamento
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CriarRedeButton
        function CriarRedeButtonPushed(app, event)
 % Obtém os parâmetros da UI
            numCamadas = app.NmerodecamadasEditField.Value;
            neurPorCamada = app.NeurniosporcamadaEditField.Value;
            funcAtivacao = app.FunodeativaoDropDown.Value;
            funcTreinamento = app.FunodetreinoDropDown.Value;

            % Criar a rede com os parâmetros
            app.rede = feedforwardnet(repmat(neurPorCamada, 1, numCamadas)); 
            app.rede.layers{end}.transferFcn = funcAtivacao;
            app.rede.trainFcn = funcTreinamento;

            disp('Rede Criada!');
        end

        % Button pushed function: TreinarRedeButton
        function TreinarRedeButtonPushed(app, event)
   % Permite ao utilizador escolher a pasta de treino
    CaminhoPasta = uigetdir('', 'Selecione a pasta com imagens de treino');
    
    % Se o utilizador cancelar a seleção, sair da função
    if CaminhoPasta == 0
        disp('Seleção de pasta cancelada.');
        return;
    end

    % Define as classes
    classes = {'circle', 'kite', 'parallelogram', 'square', 'trapezoid', 'triangle'};

    % Processa as imagens e obtém os dados de entrada e saída
    [inputs, targets] = preProcessarImagens(CaminhoPasta, classes);

    % Treinar a rede com os dados
    [app.rede, app.resultadosTreinamento] = train(app.rede, inputs, targets);

    % Atualiza o progresso no gauge
    app.valorestreinoGauge.Value = app.resultadosTreinamento.best_perf;

    disp('Rede treinada!');
        end

        % Button pushed function: VisualizarResultadosButton
        function VisualizarResultadosButtonPushed(app, event)
 % Carregar dados de teste para visualização
            classes = {'circle', 'kite','parallelogram', 'square', 'trapezoid', 'triangle'};
            CaminhoPasta = 'C:\Users\tiago\OneDrive - ISEC\Informática\2 ano\2º Semestre\CR\TP\ImageFolders\test';
            % Processa as imagens de teste
            [inputsTeste, targetsTeste] = preProcessarImagens(CaminhoPasta, classes);
            
            saidas = sim(app.rede, inputsTeste);
            
            % Exibe a matriz de confusão
            figure;
            plotconfusion(targetsTeste, saidas);
        end

        % Button pushed function: carregarButton
        function carregarButtonPushed(app, event)
 [fileName, pathName] = uigetfile('*.mat', 'Carregar Rede');
            if fileName ~= 0
                loadedData = load(fullfile(pathName, fileName));
                app.rede = loadedData.rede;
                disp('Rede carregada!');
            end
        end

        % Button pushed function: gravarButton
        function gravarButtonPushed(app, event)
% Verifica se a rede foi criada antes de tentar salvar
    if isempty(app.rede)
        uialert(app.UIFigure, 'A rede neural ainda não foi criada!', 'Erro', 'Icon', 'error');
        return;
    end
    
    % Solicita o caminho e o nome do arquivo para salvar
    [fileName, pathName] = uiputfile('*.mat', 'Salvar Rede Neural');
    
    if isequal(fileName, 0)
        return; % O usuário cancelou
    end
    
    % Salva a rede neural e os resultados de treinamento
    try
        rede = app.rede; % Acessando a rede diretamente
        resultadosTreinamento = app.resultadosTreinamento; % Acessando os resultados de treinamento
        save(fullfile(pathName, fileName), 'rede', 'resultadosTreinamento');
        disp('Rede salva com sucesso!');
    catch ME
        uialert(app.UIFigure, ['Erro ao salvar a rede: ' ME.message], 'Erro', 'Icon', 'error');
    end

        end

        % Button pushed function: CarregardesenhoatestarButton
        function CarregardesenhoatestarButtonPushed(app, event)
  % Verifica se a rede está carregada
    if isempty(app.rede)
        escolha = uiconfirm(app.UIFigure, ...
            'Nenhuma rede carregada. Deseja carregar uma agora?', ...
            'Rede não encontrada', ...
            'Options', {'Sim', 'Não'}, ...
            'DefaultOption', 'Sim', ...
            'CancelOption', 'Não');
        
        if strcmp(escolha, 'Sim')
            [ficheiro, caminho] = uigetfile('*.mat', 'Selecione o ficheiro da rede');
            if isequal(ficheiro, 0)
                return; % Utilizador cancelou
            else
                redeCarregada = load(fullfile(caminho, ficheiro));
                campos = fieldnames(redeCarregada);
                app.rede = redeCarregada.(campos{1});
            end
        else
            return;
        end
    end

    % Pede ao utilizador uma imagem
    [ficheiro, caminho] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp', 'Imagens'}, 'Selecione a imagem a classificar');
    if isequal(ficheiro, 0)
        return;
    end

    % Pré-processamento
    imagem = imread(fullfile(caminho, ficheiro));
    imagemCinza = rgb2gray(imagem);
    imagemBinaria = imbinarize(imresize(imagemCinza, [25 25]));
    entrada = double(reshape(imagemBinaria, [], 1));

    % Classificação
    saida = app.rede(entrada);
    [~, indiceClasse] = max(saida);

    % Classes (ajuste conforme necessário)
    classes = {'círculo', 'kite', 'paralelogramo', 'quadrado', 'trapezoide', 'triângulo'};
    nomeClasse = classes{indiceClasse};

    % Exibe a imagem no componente UIImage
    app.ImagemDisplay.ImageSource = fullfile(caminho, ficheiro);  % Atualiza a imagem

    % Exibe o resultado da classificação
    msgbox(['Classe: ', nomeClasse], 'Resultado da Classificação');
        end

        % Callback function
        function savetesteButtonPushed(app, event)

        end

        % Callback function
        function vizualizartesteButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConfigurareTreinarRedeTab = uitab(app.TabGroup);
            app.ConfigurareTreinarRedeTab.Title = ' Configurar e Treinar Rede';
            app.NeurniosporcamadaEditFieldLabel = uilabel(app.ConfigurareTreinarRedeTab);
            app.NeurniosporcamadaEditFieldLabel.Text = 'Neurónios por camada';
            app.NeurniosporcamadaEditField = uieditfield(app.ConfigurareTreinarRedeTab, 'numeric');
            app.NeurniosporcamadaEditField.Value = 5;
            app.CriarRedeButton = uibutton(app.ConfigurareTreinarRedeTab, 'push');
            app.CriarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @CriarRedeButtonPushed, true);
            app.CriarRedeButton.Text = '	Criar Rede';
            app.TreinarRedeButton = uibutton(app.ConfigurareTreinarRedeTab, 'push');
            app.TreinarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @TreinarRedeButtonPushed, true);
            app.TreinarRedeButton.Text = 'Treinar Rede';
            app.VisualizarResultadosButton = uibutton(app.ConfigurareTreinarRedeTab, 'push');
            app.VisualizarResultadosButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizarResultadosButtonPushed, true);
            app.VisualizarResultadosButton.Text = '	Visualizar Resultados';
            app.gravarButton = uibutton(app.ConfigurareTreinarRedeTab, 'push');
            app.gravarButton.ButtonPushedFcn = createCallbackFcn(app, @gravarButtonPushed, true);
            app.gravarButton.Text = 'gravar';
            app.carregarButton = uibutton(app.ConfigurareTreinarRedeTab, 'push');
            app.carregarButton.ButtonPushedFcn = createCallbackFcn(app, @carregarButtonPushed, true);
            app.carregarButton.Text = 'carregar';
            app.valorestreinoGaugeLabel = uilabel(app.ConfigurareTreinarRedeTab);
            app.valorestreinoGaugeLabel.Text = '	valores treino';
            app.valorestreinoGauge = uigauge(app.ConfigurareTreinarRedeTab, 'linear');
            app.FunodeativaoDropDownLabel = uilabel(app.ConfigurareTreinarRedeTab);
            app.FunodeativaoDropDownLabel.Text = '	Função de ativação';
            app.FunodeativaoDropDown = uidropdown(app.ConfigurareTreinarRedeTab);
            app.FunodeativaoDropDown.Items = {'tansig', 'purelin', 'logsig'};
            app.FunodeativaoDropDown.Value = 'tansig';
            app.FunodetreinoDropDownLabel = uilabel(app.ConfigurareTreinarRedeTab);
            app.FunodetreinoDropDownLabel.Text = '	Função de treino';
            app.FunodetreinoDropDown = uidropdown(app.ConfigurareTreinarRedeTab);
            app.FunodetreinoDropDown.Items = {'trainlm', 'traingd', 'trainrp', 'trainbfg'};
            app.FunodetreinoDropDown.Value = 'trainlm';
            app.NmerodecamadasEditFieldLabel = uilabel(app.ConfigurareTreinarRedeTab);
            app.NmerodecamadasEditFieldLabel.Text = 'Número de camadas';
            app.NmerodecamadasEditField = uieditfield(app.ConfigurareTreinarRedeTab, 'numeric');
            app.NmerodecamadasEditField.Value = 1;
            app.ImgtesteTab = uitab(app.TabGroup);
            app.ImgtesteTab.Title = 'Img teste';
            app.CarregardesenhoatestarButton = uibutton(app.ImgtesteTab, 'push');
            app.CarregardesenhoatestarButton.ButtonPushedFcn = createCallbackFcn(app, @CarregardesenhoatestarButtonPushed, true);
            app.CarregardesenhoatestarButton.Text = 'Carregar desenho a testar';
            app.ImagemDisplay = uiimage(app.ImgtesteTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end